classdef MTO_Platform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MTOPlatformUIFigure          matlab.ui.Figure
        MTOPlatformGridLayout        matlab.ui.container.GridLayout
        MTOPlatformTabGroup          matlab.ui.container.TabGroup
        TestTab                      matlab.ui.container.Tab
        TestGridLayout               matlab.ui.container.GridLayout
        TPanel1                      matlab.ui.container.Panel
        TP1GridLayout                matlab.ui.container.GridLayout
        AlgorithmDropDownLabel       matlab.ui.control.Label
        TAlgorithmDropDown           matlab.ui.control.DropDown
        TAlgorithmTree               matlab.ui.container.Tree
        TProblemTree                 matlab.ui.container.Tree
        TProblemDropDown             matlab.ui.control.DropDown
        ProblemDropDownLabel         matlab.ui.control.Label
        TTaskTypeDropDown            matlab.ui.control.DropDown
        TaskLabel_2                  matlab.ui.control.Label
        SpecialLabel_2               matlab.ui.control.Label
        TSpecialTypeDropDown         matlab.ui.control.DropDown
        TPanel2                      matlab.ui.container.Panel
        TP2GridLayout                matlab.ui.container.GridLayout
        TP21GridLayout               matlab.ui.container.GridLayout
        TShowTypeDropDown            matlab.ui.control.DropDown
        TSaveButton                  matlab.ui.control.Button
        TP24GridLayout               matlab.ui.container.GridLayout
        TStartButton                 matlab.ui.control.Button
        TResetButton                 matlab.ui.control.Button
        TUIAxes                      matlab.ui.control.UIAxes
        TPanel3                      matlab.ui.container.Panel
        TP3GridLayout                matlab.ui.container.GridLayout
        OutputTextAreaLabel          matlab.ui.control.Label
        TOutputTextArea              matlab.ui.control.TextArea
        Emailint_lyccugeducnLabel    matlab.ui.control.Label
        ExperimentTab                matlab.ui.container.Tab
        ExperimentsGridLayout        matlab.ui.container.GridLayout
        EPanel3                      matlab.ui.container.Panel
        EP3GridLayout                matlab.ui.container.GridLayout
        ETableTabGroup               matlab.ui.container.TabGroup
        ETableTab                    matlab.ui.container.Tab
        EP3TGridLayout               matlab.ui.container.GridLayout
        EP3T1GridLayout              matlab.ui.container.GridLayout
        ETestTypeDropDown            matlab.ui.control.DropDown
        EAlgorithmDropDown           matlab.ui.control.DropDown
        EShowTypeDropDown            matlab.ui.control.DropDown
        EDataTypeDropDown            matlab.ui.control.DropDown
        EHighlightTypeDropDown       matlab.ui.control.DropDown
        ESaveTableButton             matlab.ui.control.Button
        EDataFormatEditField         matlab.ui.control.EditField
        EUITable                     matlab.ui.control.Table
        EFigureTab                   matlab.ui.container.Tab
        EP3FGridLayout               matlab.ui.container.GridLayout
        EP3F1GridLayout              matlab.ui.container.GridLayout
        EProblemsDropDown            matlab.ui.control.DropDown
        EYLimTypeDropDown            matlab.ui.control.DropDown
        ESaveAllFigureButton         matlab.ui.control.Button
        EFigureTypeDropDown          matlab.ui.control.DropDown
        EMarkerIndicesEditField      matlab.ui.control.NumericEditField
        EConvergenceTypeDropDown     matlab.ui.control.DropDown
        EXLimTypeDropDown            matlab.ui.control.DropDown
        EConvergenceTrendUIAxes      matlab.ui.control.UIAxes
        EPanel1                      matlab.ui.container.Panel
        EP1GridLayout                matlab.ui.container.GridLayout
        EProblemsAddButton           matlab.ui.control.Button
        EAlgorithmsAddButton         matlab.ui.control.Button
        ERepsEditField               matlab.ui.control.NumericEditField
        ERunTimesEditFieldLabel      matlab.ui.control.Label
        EAlgorithmsListBox           matlab.ui.control.ListBox
        AlgorithmsLabel              matlab.ui.control.Label
        EProblemsListBox             matlab.ui.control.ListBox
        ProblemsLabel                matlab.ui.control.Label
        ParallelDropDownLabel        matlab.ui.control.Label
        EParallelDropDown            matlab.ui.control.DropDown
        TaskLabel                    matlab.ui.control.Label
        ETaskTypeDropDown            matlab.ui.control.DropDown
        ELoadDataButton              matlab.ui.control.Button
        SpecialLabel                 matlab.ui.control.Label
        ESpecialTypeDropDown         matlab.ui.control.DropDown
        EPanel2                      matlab.ui.container.Panel
        EP2GridLayout                matlab.ui.container.GridLayout
        EStartButton                 matlab.ui.control.Button
        EPauseButton                 matlab.ui.control.Button
        EStopButton                  matlab.ui.control.Button
        EAlgorithmsTree              matlab.ui.container.Tree
        EProblemsTree                matlab.ui.container.Tree
        ESelectedAlgorithmsLabel     matlab.ui.control.Label
        ESelectedProblemsLabel       matlab.ui.control.Label
        EAlgorithmsDelButton         matlab.ui.control.Button
        EProblemsDelButton           matlab.ui.control.Button
        ESaveDataButton              matlab.ui.control.Button
        DataProcessTab               matlab.ui.container.Tab
        DataProcessGridLayout        matlab.ui.container.GridLayout
        DPanel1                      matlab.ui.container.Panel
        DP1GridLayout                matlab.ui.container.GridLayout
        DDataProcessModuleLabel      matlab.ui.control.Label
        DP1Panel1                    matlab.ui.container.Panel
        DP1P1GridLayout              matlab.ui.container.GridLayout
        DLoadDataButton              matlab.ui.control.Button
        DDeleteDataButton            matlab.ui.control.Button
        DSaveDataButton              matlab.ui.control.Button
        DLoadDataorSelectandDeleteSaveDataLabel_3  matlab.ui.control.Label
        DLoadDataorSelectandDeleteSaveDataLabel_4  matlab.ui.control.Label
        DP1Panel2                    matlab.ui.container.Panel
        DP1P2GridLayout              matlab.ui.container.GridLayout
        DSelectandSplitDataLabel     matlab.ui.control.Label
        DRepsSplitButton             matlab.ui.control.Button
        DAlgorithmsSplitButton       matlab.ui.control.Button
        DProblemsSplitButton         matlab.ui.control.Button
        DP1Panel3                    matlab.ui.container.Panel
        DP1P3GridLayout              matlab.ui.container.GridLayout
        DSelectandMergeDataLabel     matlab.ui.control.Label
        DRepsMergeButton             matlab.ui.control.Button
        DAlgorithmsMergeButton       matlab.ui.control.Button
        DProblemsMergeButton         matlab.ui.control.Button
        DP1Panel4                    matlab.ui.container.Panel
        DP1P4GridLayout              matlab.ui.container.GridLayout
        DUpandDownDataLabel          matlab.ui.control.Label
        DUpButton                    matlab.ui.control.Button
        DDownButton                  matlab.ui.control.Button
        DPanel2                      matlab.ui.container.Panel
        DP2GridLayout                matlab.ui.container.GridLayout
        DDataTree                    matlab.ui.container.Tree
        ESelectedAlgoContextMenu     matlab.ui.container.ContextMenu
        SelectedAlgoSelectAllMenu    matlab.ui.container.Menu
        DDataContextMenu             matlab.ui.container.ContextMenu
        SelectedAlgoSelectAllMenu_2  matlab.ui.container.Menu
        ESelectedProbContextMenu     matlab.ui.container.ContextMenu
        SelectedProbSelectAllMenu    matlab.ui.container.Menu
        EAlgorithmsContextMenu       matlab.ui.container.ContextMenu
        AlgorithmsSelectAllMenu      matlab.ui.container.Menu
        EProblemsContextMenu         matlab.ui.container.ContextMenu
        ProblemsSelectAllMenu        matlab.ui.container.Menu
    end

    properties (Access = public)
        algo_load % cell of algorithms loaded from folder
        prob_load % cell of problems loaded from folder
        
        % convergence axes set
        line_width = 1.5
        marker_list = {'o', '*', 'x', '^', '+', 'p', 'v', 's', 'd', '<', '>', 'h'}
        marker_size = 7
        
        % Test Module
        Tdata % data
        
        % Experiment Module
        Edata % data
        Estop_flag % stop button clicked flag
        Efitness % fitness calculated
        Efitness_cv % constraint violation
        Eminfitness % competitive fitness calculated
        Eminfitness_cv
        Etime_used % time_used calculated
        Etable_data % table data for calculate
        Etable_view % table data view
        Etable_view_test % table data view test
        Etable_reps % table reps
        
        % Data Process Module
        Ddata_mark % legal data node index
    end
    
    methods (Access = public)
        
        function readAlgoProb(app, label_str)
            % load the algorithms and problems list
            
            app.algo_load = app.readList('../Algorithms', label_str);
            app.prob_load = app.readList('../Problems', label_str);
            
            app.algo_load = sort_nat(app.algo_load);
            app.prob_load = sort_nat(app.prob_load);
        end
        
        function read_list = readList(app, folder_name, label_str)
            % read file name list with labels
            
            read_list = {};
            folders = split(genpath(fullfile(fileparts(mfilename('fullpath')), folder_name)),pathsep);
            for i = 1:length(folders)
                files = what(folders{i});
                files = files.m;
                for j = 1:length(files)
                    fid = fopen(files{j});
                    fgetl(fid);
                    str = regexprep(fgetl(fid),'^\s*%\s*','','once');
                    fclose(fid);
                    label_find = regexp(str,'(?<=<).*?(?=>)','match');
                    label_all = {};
                    for k = 1:length(label_find)
                        label_all = [label_all, split(label_find{k}, '/')'];
                    end
                    if sum(ismember(label_str, label_all)) == length(label_str)
                        
                        read_list = [read_list, files{j}(1:end-2)];
                    end
                end
            end
        end
        
        function TloadAlgoProb(app)
            % load the algorithms and problems in Test module
            
            label_str = {app.TTaskTypeDropDown.Value, app.TSpecialTypeDropDown.Value};
            app.readAlgoProb(label_str);
            app.TAlgorithmDropDown.Items = {};
            app.TProblemDropDown.Items = {};
            app.TAlgorithmDropDown.Items = strrep(app.algo_load, '_', '-');
            app.TAlgorithmDropDown.ItemsData = app.algo_load;
            app.TProblemDropDown.Items = strrep(app.prob_load, '_', '-');
            app.TProblemDropDown.ItemsData = app.prob_load;
        end
        
        function EloadAlgoProb(app)
            % load the algorithms and problems in Experiment module
            
            label_str = {app.ETaskTypeDropDown.Value, app.ESpecialTypeDropDown.Value};
            app.readAlgoProb(label_str);
            app.EAlgorithmsListBox.Items(:) = [];
            app.EProblemsListBox.Items(:) = [];
            app.EAlgorithmsListBox.Items = strrep(app.algo_load, '_', '-');
            app.EAlgorithmsListBox.ItemsData = app.algo_load;
            app.EProblemsListBox.Items = strrep(app.prob_load, '_', '-');
            app.EProblemsListBox.ItemsData = app.prob_load;
        end
        
        function TstartEnable(app, value)
            % change controler enable when start button pused and end
            % in Test module
            
            app.TStartButton.Enable = value;
            app.TTaskTypeDropDown.Enable = value;
            app.TSpecialTypeDropDown.Enable = value;
            app.TAlgorithmDropDown.Enable = value;
            app.TAlgorithmTree.Enable = value;
            app.TProblemDropDown.Enable = value;
            app.TProblemTree.Enable = value;
        end
        
        function EstartEnable(app, value)
            % change controler enable when start button pused and end
            % in Experiment module
            
            app.EStartButton.Enable = value;
            app.ERepsEditField.Enable = value;
            app.EParallelDropDown.Enable = value;
            app.ETaskTypeDropDown.Enable = value;
            app.ESpecialTypeDropDown.Enable = value;
            app.EAlgorithmsAddButton.Enable = value;
            app.EProblemsAddButton.Enable = value;
            app.EAlgorithmsListBox.Enable = value;
            app.EProblemsListBox.Enable = value;
            app.EAlgorithmsDelButton.Enable = value;
            app.EProblemsDelButton.Enable = value;
            app.EAlgorithmsTree.Enable = value;
            app.EProblemsTree.Enable = value;
            app.ELoadDataButton.Enable = value;
            app.ESaveDataButton.Enable = value;
            app.EPauseButton.Enable = ~value;
            app.EStopButton.Enable = ~value;
        end
        
        function EcheckPauseStopStatus(app)
            % This function can be called at any time to check that status of the pause and stop buttons.
            % If paused, it will wait until un-paused.
            % If stopped, it will throw an error to break execution. The error will not be thrown.
            
            if app.Estop_flag
                app.EstartEnable(true);
                error('User Stop');
            end
            
            if strcmp(app.EPauseButton.Text, 'Resume')
                waitfor(app.EPauseButton,'Text', 'Pause');
            end
        end
        
        function TupdateAlgorithm(app)
            % update algorithm tree in Test module
            
            app.TAlgorithmTree.Children.delete;
            
            algo_name = app.TAlgorithmDropDown.Value;
            eval(['algo_obj = ', algo_name, '("', strrep(algo_name, '_', '-'), '");']);
            algo_node = uitreenode(app.TAlgorithmTree);
            algo_node.Text = algo_obj.getName();
            algo_node.NodeData = algo_obj;
            algo_node.ContextMenu = app.ESelectedProbContextMenu;
            
            % child parameter node
            parameter = algo_obj.getParameter();
            for p = 1:2:length(parameter)
                para_name_node = uitreenode(algo_node);
                para_name_node.Text = ['[ ', parameter{p}, ' ]'];
                para_name_node.NodeData = para_name_node.Text;
                para_name_node.ContextMenu = app.ESelectedAlgoContextMenu;
                para_value_node = uitreenode(algo_node);
                para_value_node.Text = parameter{p+1};
                para_value_node.ContextMenu = app.ESelectedAlgoContextMenu;
            end
            
            expand(algo_node);
        end
        
        function TupdateProblem(app)
            % update problem tree in Test module
            
            app.TProblemTree.Children.delete;
            
            prob_name = app.TProblemDropDown.Value;
            eval(['prob_obj = ', prob_name, '("', strrep(prob_name, '_', '-'), '");']);
            prob_node = uitreenode(app.TProblemTree);
            prob_node.Text = prob_obj.getName();
            prob_node.NodeData = prob_obj;
            prob_node.ContextMenu = app.ESelectedProbContextMenu;
            
            
            % child parameter node
            parameter = prob_obj.getParameter();
            for p = 1:2:length(parameter)
                para_name_node = uitreenode(prob_node);
                para_name_node.Text = ['[ ', parameter{p}, ' ]'];
                para_name_node.NodeData = para_name_node.Text;
                para_name_node.ContextMenu = app.ESelectedProbContextMenu;
                para_value_node = uitreenode(prob_node);
                para_value_node.Text = parameter{p+1};
                para_value_node.ContextMenu = app.ESelectedProbContextMenu;
            end
            
            expand(prob_node);
        end
        
        function TupdateUIAxes(app)
            % update UI Axes in Test module
            
            cla(app.TUIAxes, 'reset');
            type = app.TShowTypeDropDown.Value;
            switch type
                case 1 % Tasks Figure (1D unified)
                    app.TupdateTasksFigure();
                case 2 % Tasks Figure (1D real)
                    app.TupdateTasksFigure();
                case 3 % Feasible Region (2D)
                    app.TupdateFeasibleRegion();
                case 4 % Convergence (Obj)
                    app.TupdateConvergence();
            end
        end
        
        function TupdateTasksFigure(app)
            % update selected problem tasks figure in Test module
            
            Tasks = app.TProblemTree.Children(1).NodeData.getTasks();
            no_of_tasks = length(Tasks);
            
            x = 0:1/1000:1;
            f = zeros(size(x));
            
            legend_cell = {};
            plot_handle = {};
            color = colororder;
            for no = 1:no_of_tasks
                
                for i = 1:length(x)
                    minrange = Tasks(no).Lb(1);
                    maxrange = Tasks(no).Ub(1);
                    y = maxrange - minrange;
                    vars = y .* x(i) + minrange;
                    [ff, con] = Tasks(no).fnc(vars);
                    if con > 0
                        f(i) = NaN;
                    else
                        f(i) = ff;
                    end
                end
                
                fmin = min(f);
                fmax = max(f);
                if app.TShowTypeDropDown.Value == 1 % unified
                    f = (f - fmin) / (fmax - fmin);
                end
                
                p1 = plot(app.TUIAxes, x, f);
                p1.Color = color(mod(no-1, size(color, 1))+1, :);
                p1.LineWidth = 1;
                hold(app.TUIAxes, 'on');
                
                if ~isnan(f)
                    xmin = x(f == min(f));
                    fmin = min(f) * ones(size(xmin));
                    p2 = plot(app.TUIAxes, xmin, fmin, '^');
                    p2.MarkerSize = 8;
                    p2.MarkerFaceColor = color(mod(no-1, size(color, 1))+1, :);
                    p2.MarkerEdgeColor = color(mod(no-1, size(color, 1))+1, :);
                    hold(app.TUIAxes, 'on');
                end
                
                legend_cell = [legend_cell, ['Task', num2str(no)]];
                plot_handle = [plot_handle, p1];
            end
            xlim(app.TUIAxes, [0, 1]);
            xlabel(app.TUIAxes, 'Variable Value');
            ylabel(app.TUIAxes, 'Objective Value');
            legend(app.TUIAxes, plot_handle, legend_cell);
        end
        
        function TupdateFeasibleRegion(app)
            % update selected problem tasks feasible region
            
            if ~strcmp(app.TSpecialTypeDropDown.Value, 'Constrained')
                return;
            end
            
            Tasks = app.TProblemTree.Children(1).NodeData.getTasks();
            no_of_tasks = length(Tasks);
            
            x = 0:1/150:1;
            
            legend_cell = {};
            plot_handle = {};
            
            color = colororder;
            for no = 1:no_of_tasks
                %x = x + (1/100)/3;
                %x = x(1:end-1);
                x1 = [];
                x2 = [];
                minrange = Tasks(no).Lb(1);
                maxrange = Tasks(no).Ub(1);
                y = maxrange - minrange;
                
                for i = 1:length(x)
                    for j = 1:length(x)
                        vars1 = y .* x(i) + minrange;
                        vars2 = y .* x(j) + minrange;
                        [ff, con] = Tasks(no).fnc([vars1, vars2]);
                        if con <= 0
                            x1 = [x1, x(i)];
                            x2 = [x2, x(j)];
                        end
                    end
                end
                
                p1 = scatter(app.TUIAxes, x1, x2, 6, 'filled');
                p1.MarkerFaceAlpha = 0.6;
                p1.MarkerEdgeAlpha = 0.6;
                p1.MarkerEdgeColor = color(mod(no-1, size(color, 1))+1, :);
                % p1.MarkerFaceColor = color(mod(no-1, size(color, 1))+1, :);
                hold(app.TUIAxes, 'on');
                
                legend_cell = [legend_cell, ['T', num2str(no)]];
                plot_handle = [plot_handle, p1];
            end
            xlim(app.TUIAxes, [0, 1]);
            ylim(app.TUIAxes, [0, 1]);
            legend(app.TUIAxes, plot_handle, legend_cell);
        end
        
        function TupdateConvergence(app)
            % update convergence in Test module
            
            % check app.Edata
            if isempty(app.Tdata)
                return;
            end
            
            % draw
            tasks_name = {};
            for task = 1:app.Tdata.tasks_num
                convergence = app.Tdata.convergence(task, :);
                if isfield(app.Tdata, 'convergence_cv');
                    convergence_cv = app.Tdata.convergence_cv(task, :);
                    convergence(convergence_cv > 0) = NaN;
                end
                x = 1:size(convergence,2);
                x = x / length(x) * app.Tdata.sub_eva;
                y = convergence;
                if task > length(app.marker_list)
                    marker = '';
                else
                    marker = app.marker_list{task};
                end
                
                p = plot(app.TUIAxes, x, y, ['-', marker]);
                p.LineWidth = app.line_width;
                indices = round(length(y)/app.EMarkerIndicesEditField.Value);
                p.MarkerIndices = indices:indices:length(y)-round(indices/2);
                p.MarkerSize = app.marker_size;
                hold(app.TUIAxes, 'on');
                
                xlabel(app.TUIAxes, 'Evaluation');
                ylabel(app.TUIAxes, app.TShowTypeDropDown.Value);
                grid(app.TUIAxes, 'on');
                tasks_name = [tasks_name, ['T', num2str(task)]];
            end
            legend(app.TUIAxes, tasks_name);
        end
        
        function Toutput(app, output_str)
            if strcmp(app.TOutputTextArea.Value, '')
                app.TOutputTextArea.Value = output_str;
            else
                app.TOutputTextArea.Value = [app.TOutputTextArea.Value; output_str];
            end
            drawnow;
        end
        
        function EresetTableAlgorithmDropDown(app, algo_cell)
            % reset table's algorithms drop down in Experiment module
            
            algo_index = [];
            for algo = 1:length(algo_cell)
                algo_index = [algo_index, algo];
            end
            app.EAlgorithmDropDown.Items = algo_cell;
            app.EAlgorithmDropDown.ItemsData = algo_index;
            app.EAlgorithmDropDown.Value = 1;
        end
        
        function EresetTable(app, algo_cell, prob_cell, tasks_num_list)
            % reset table in Experiment module
            
            app.EUITable.Data = {};
            app.Etable_data = {};
            app.Etable_view = {};
            app.Etable_view_test = {};
            drawnow;
            prob_row_cell = {};
            for prob = 1:length(prob_cell)
                for task = 1:tasks_num_list(prob)
                    if app.Edata.tasks_num_list(prob) == 1
                        prob_row_cell = [prob_row_cell, prob_cell{prob}];
                    else
                        prob_row_cell = [prob_row_cell, [prob_cell{prob}, '-T', num2str(task)]];
                    end
                end
            end
            
            total_type = '+/-/=';
            if strcmp(app.ETestTypeDropDown.Value, 'FR rank')
                total_type = 'Total FR Rank';
            end
            
            if strcmp(app.EDataTypeDropDown.Value, 'Obj')
                app.EUITable.RowName = prob_row_cell;
                app.EUITable.RowName = [app.EUITable.RowName; total_type];
            elseif strcmp(app.EDataTypeDropDown.Value, 'min(Obj)')
                app.EUITable.RowName = prob_cell;
                app.EUITable.RowName = [app.EUITable.RowName; total_type];
            else
                app.EUITable.RowName = prob_cell;
            end
            app.EUITable.ColumnName = algo_cell;
        end
        
        function EcalculatePre(app)
            % calculate fitness, std, time used
            
            if isempty(app.Edata)
                return
            end
            
            app.Efitness = [];
            app.Efitness_cv = [];
            app.Eminfitness = [];
            app.Eminfitness_cv = [];
            app.Etime_used = [];
            % calculate fitness
            for algo = 1:length(app.Edata.algo_cell)
                row_i = 1;
                for prob = 1:length(app.Edata.prob_cell)
                    tasks_num = app.Edata.tasks_num_list(prob);
                    temp_i = row_i;
                    for task = 1:tasks_num
                        convergence_task = app.Edata.result(prob, algo).convergence(task:tasks_num:end, :);
                        if isfield(app.Edata.result(prob, algo), 'convergence_cv')
                            convergence_cv_task = app.Edata.result(prob, algo).convergence_cv(task:tasks_num:end, :);
                        else
                            convergence_cv_task = zeros(size(app.Edata.result(prob, algo).convergence(task:tasks_num:end, :)));
                        end
                        app.Efitness(row_i, algo, :) = convergence_task(:, end);
                        app.Efitness_cv(row_i, algo, :) = convergence_cv_task(:, end);
                        row_i = row_i + 1;
                    end
                    [app.Eminfitness(prob, algo, :), min_idx] = min(app.Efitness(temp_i:temp_i+tasks_num-1, algo, :), [], 1);
                    min_cv_temp = app.Efitness_cv(temp_i:temp_i+tasks_num-1, algo, :);
                    for i = 1:length(min_idx)
                        app.Eminfitness_cv(prob, algo, i) = min_cv_temp(min_idx(i), i);
                    end
                    app.Etime_used(prob, algo) = app.Edata.result(prob, algo).clock_time;
                end
            end
        end
        
        function EupdateTableReps(app)
            % update table reps per run
            
            if ~strcmp(app.EDataTypeDropDown.Value, 'Reps')
                return;
            end
            app.EUITable.Data = sprintfc('%d', app.Etable_reps);
            drawnow;
        end
        
        function EupdateTableObjective(app)
            % update table fitness
            
            if strcmp(app.EDataTypeDropDown.Value, 'Obj')
                data_fitness = app.Efitness;
                data_fitness_cv = app.Efitness_cv;
            elseif strcmp(app.EDataTypeDropDown.Value, 'min(Obj)')
                data_fitness = app.Eminfitness;
                data_fitness_cv = app.Eminfitness_cv;
            else
                return;
            end
            show_type = app.EShowTypeDropDown.Value;
            format_str = app.EDataFormatEditField.Value;
            
            switch show_type
                case 'Mean' % Mean
                    fitness_mean = mean(data_fitness, 3);
                    app.Etable_data = fitness_mean;
                    app.Etable_view = sprintfc(format_str, fitness_mean);
                case 'Mean&Std' % Mean&Std
                    fitness_mean = mean(data_fitness, 3);
                    fitness_std = std(data_fitness, 0, 3);
                    app.Etable_data = fitness_mean;
                    x = zeros([size(fitness_mean, 1), 2*size(fitness_mean, 2)]);
                    x(:, 1:2:end) = fitness_mean;
                    x(:, 2:2:end) = fitness_std;
                    app.Etable_view = sprintfc(format_str, x);
                case 'Mean&CV' % Mean&CV
                    fitness_mean = mean(data_fitness, 3);
                    fitness_cv = mean(data_fitness_cv, 3);
                    app.Etable_data = fitness_mean;
                    x = zeros([size(fitness_mean, 1), 2*size(fitness_mean, 2)]);
                    x(:, 1:2:end) = fitness_mean;
                    x(:, 2:2:end) = fitness_cv;
                    app.Etable_view = sprintfc(format_str, x);
                case 'Mean&CV&FR' % Mean&CV&FR
                    fitness_mean = mean(data_fitness, 3);
                    fitness_cv = mean(data_fitness_cv, 3);
                    fitness_fr = sum((data_fitness_cv == 0), 3) ./ size(data_fitness_cv, 3);
                    app.Etable_data = fitness_mean;
                    x = zeros([size(fitness_mean, 1), 3*size(fitness_mean, 2)]);
                    x(:, 1:3:end) = fitness_mean;
                    x(:, 2:3:end) = fitness_cv;
                    x(:, 3:3:end) = fitness_fr;
                    app.Etable_view = sprintfc(format_str, x);
                case 'Std'
                    fitness_std = std(data_fitness, 0, 3);
                    app.Etable_data = fitness_std;
                    app.Etable_view = sprintfc(format_str, fitness_std);
                case 'Median'
                    fitness_median = median(data_fitness, 3);
                    app.Etable_data = fitness_median;
                    app.Etable_view = sprintfc(format_str, fitness_median);
                case 'Best'
                    fitness_min = min(data_fitness, [], 3);
                    app.Etable_data = fitness_min;
                    app.Etable_view = sprintfc(format_str, fitness_min);
                case 'Worst'
                    % fitness_nan = max(isnan(data_fitness), [], 3);
                    fitness_max = max(data_fitness, [], 3);
                    % fitness_max(fitness_nan == 1) = NaN;
                    app.Etable_data = fitness_max;
                    app.Etable_view = sprintfc(format_str, fitness_max);
                case 'CV'
                    fitness_cv = mean(data_fitness_cv, 3);
                    app.Etable_data = fitness_cv;
                    app.Etable_view = sprintfc(format_str, fitness_cv);
                case 'FR'
                    fitness_fr = sum((data_fitness_cv == 0), 3) ./ size(data_fitness_cv, 3);
                    app.Etable_data = fitness_fr;
                    app.Etable_view = sprintfc(format_str, fitness_fr);
            end
            
            if ~isempty(app.Etable_view_test)
                for algo = 1:size(app.Etable_data, 2)
                    for row_i = 1:size(app.Etable_data, 1)
                        if size(app.Etable_view_test, 2) < algo
                            app.EUITable.Data{row_i, algo} = app.Etable_view{row_i, algo};
                        else
                            app.EUITable.Data{row_i, algo} = [app.Etable_view{row_i, algo}, ' ', app.Etable_view_test{row_i, algo}];
                        end
                        drawnow;
                    end
                    if size(app.Etable_view_test, 2) < algo
                        app.EUITable.Data{size(app.Etable_data, 1)+1, algo} = '';
                    else
                        app.EUITable.Data{size(app.Etable_data, 1)+1, algo} = app.Etable_view_test{size(app.Etable_data, 1)+1, algo};
                    end
                end
            else
                app.EUITable.Data = app.Etable_view;
            end
            drawnow;
        end
        
        function EupdateTableScore(app)
            % update table score
            
            if ~strcmp(app.EDataTypeDropDown.Value, 'Score')
                return;
            end
            
            score = zeros(size(app.Etable_reps));
            row_i = 1;
            for prob = 1:length(app.Edata.prob_cell)
                tasks_num = app.Edata.tasks_num_list(prob);
                for task = 1:tasks_num
                    mean_task = nanmean(app.Efitness(row_i, :, :), 'all');
                    std_task = std(app.Efitness(row_i, :, :), 0, 'all');
                    for algo = 1:length(app.Edata.algo_cell)
                        score(prob, algo) = score(prob, algo) + nanmean((app.Efitness(row_i, algo, :) - mean_task)./std_task);
                    end
                    row_i = row_i + 1;
                end
            end
            
            app.Etable_data = score;
            app.Etable_view = sprintfc(app.EDataFormatEditField.Value, app.Etable_data);
            app.EUITable.Data = app.Etable_view;
            drawnow;
        end
        
        function EupdateTableTimeUsed(app)
            % update table time used
            
            time_used = app.Etime_used;
            app.Etable_data = time_used;
            time_used_char = {};
            for prob = 1:size(app.Etable_data, 1)
                for algo = 1:size(app.Etable_data, 2)
                    time_used_char{prob, algo} = char(duration([0, 0, app.Etable_data(prob, algo)]));
                end
            end
            app.EUITable.Data = time_used_char;
            drawnow;
        end
        
        function EupdateTableTest(app)
            % update table fitness test
            
            if ~strfind(app.EDataTypeDropDown.Value, 'Obj')
                return;
            end
            test_type = app.ETestTypeDropDown.Value;
            algo_selected = app.EAlgorithmDropDown.Value;
            app.Etable_view_test = {};
            
            if strcmp(test_type, 'None')
                app.EUITable.Data = app.Etable_view;
                return;
            end
            
            % feasible rate
            if strcmp(test_type, 'FR rank')
                switch app.EDataTypeDropDown.Value
                    case 'Obj'
                        fitness_fr = sum((app.Efitness_cv == 0), 3) ./ size(app.Efitness_cv, 3);
                    case 'min(Obj)'
                        fitness_fr = sum((app.Eminfitness_cv == 0), 3) ./ size(app.Eminfitness_cv, 3);
                end
                for row_i = 1:size(app.Etable_data, 1)
                    a = fitness_fr(row_i, :);
                    [~,ia,ic] = unique(a);
                    [~,b] = sort(a, 'descend');
                    b(b) = (1:numel(a))';
                    b = b(ia(ic));
                    fr_rank(row_i, :) = b;
                    for algo = 1:size(app.Etable_data, 2)
                        app.Etable_view_test{row_i, algo} = num2str(fr_rank(row_i, algo));
                        drawnow;
                    end
                end
                sign_p = sum(fr_rank, 1);
                for algo = 1:size(app.Etable_data, 2)
                    app.Etable_view_test{size(app.Etable_data, 1)+1, algo} = num2str(sign_p(algo));
                    drawnow;
                end
                for algo = 1:size(app.Etable_data, 2)
                    for row_i = 1:size(app.Etable_data, 1)
                        if size(app.Etable_view_test, 2) < algo
                            app.EUITable.Data{row_i, algo} = app.Etable_view{row_i, algo};
                        else
                            app.EUITable.Data{row_i, algo} = [app.Etable_view{row_i, algo}, ' (', app.Etable_view_test{row_i, algo}, ')'];
                        end
                        drawnow;
                    end
                    if size(app.Etable_view_test, 2) < algo
                        app.EUITable.Data{size(app.Etable_data, 1)+1, algo} = '';
                    else
                        app.EUITable.Data{size(app.Etable_data, 1)+1, algo} = app.Etable_view_test{size(app.Etable_data, 1)+1, algo};
                    end
                    drawnow;
                end
                return;
            end
            
            % Rank sum or Signed rank test
            for algo = 1:size(app.Etable_data, 2)
                if algo == algo_selected
                    app.Etable_view_test{size(app.Etable_data, 1)+1, algo} = 'Base';
                    continue;
                end
                sign_p = [0 0 0];
                for row_i = 1:size(app.Etable_data, 1)
                    switch app.EDataTypeDropDown.Value
                        case 'Obj'
                            x1 = reshape(app.Efitness(row_i, algo, :), 1, length(app.Efitness(row_i, algo, :)));
                            x2 = reshape(app.Efitness(row_i, algo_selected, :), 1, length(app.Efitness(row_i, algo_selected, :)));
                        case 'min(Obj)'
                            x1 = reshape(app.Eminfitness(row_i, algo, :), 1, length(app.Eminfitness(row_i, algo, :)));
                            x2 = reshape(app.Eminfitness(row_i, algo_selected, :), 1, length(app.Eminfitness(row_i, algo_selected, :)));
                    end
                    
                    p = 0;
                    if sum(~isnan(x1)) == length(x1) && sum(~isnan(x2)) == length(x2)
                        % without NaN
                        if strcmp(test_type, 'Rank sum test')
                            p = ranksum(x1, x2);
                        elseif strcmp(test_type, 'Signed rank test')
                            p = signrank(x1, x2);
                        end
                        if p < 0.05
                            if app.Etable_data(row_i, algo) < app.Etable_data(row_i, algo_selected)
                                app.Etable_view_test{row_i, algo} = '+';
                                sign_p(1) = sign_p(1) + 1;
                            else
                                app.Etable_view_test{row_i, algo} = '-';
                                sign_p(2) = sign_p(2) + 1;
                            end
                        else
                            app.Etable_view_test{row_i, algo} = '=';
                            sign_p(3) = sign_p(3) + 1;
                        end
                    else
                        % Contains NaN
                        if sum(~isnan(x1)) == length(x1) && sum(~isnan(x2)) ~= length(x2)
                            app.Etable_view_test{row_i, algo} = '+';
                            sign_p(1) = sign_p(1) + 1;
                        elseif sum(~isnan(x1)) ~= length(x1) && sum(~isnan(x2)) == length(x2)
                            app.Etable_view_test{row_i, algo} = '-';
                            sign_p(2) = sign_p(2) + 1;
                        elseif sum(~isnan(x1)) ~= length(x1) && sum(~isnan(x2)) ~= length(x2)
                            app.Etable_view_test{row_i, algo} = '=';
                            sign_p(3) = sign_p(3) + 1;
                        end
                    end
                end
                app.Etable_view_test{size(app.Etable_data, 1)+1, algo} = sprintf('%d/%d/%d', sign_p);
            end
            for algo = 1:size(app.Etable_data, 2)
                for row_i = 1:size(app.Etable_data, 1)
                    if size(app.Etable_view_test, 2) < algo
                        app.EUITable.Data{row_i, algo} = app.Etable_view{row_i, algo};
                    else
                        app.EUITable.Data{row_i, algo} = [app.Etable_view{row_i, algo}, ' ', app.Etable_view_test{row_i, algo}];
                    end
                    drawnow;
                end
                if size(app.Etable_view_test, 2) < algo
                    app.EUITable.Data{size(app.Etable_data, 1)+1, algo} = '';
                else
                    app.EUITable.Data{size(app.Etable_data, 1)+1, algo} = app.Etable_view_test{size(app.Etable_data, 1)+1, algo};
                end
                drawnow;
            end
        end
        
        function EupdateTableHighlight(app)
            % update table highlight
            
            highlight_type = app.EHighlightTypeDropDown.Value;
            
            % highlight best value
            app.EUITable.removeStyle();
            high_color = uistyle('BackgroundColor', [0.67,0.95,0.67]);
            font_bold = uistyle('FontWeight', 'bold');
            low_color = uistyle('BackgroundColor', [1.00,0.60,0.60]);
            if size(app.Etable_data, 2) <= 1
                drawnow;
                return;
            end
            for row_i = 1:size(app.Etable_data, 1)
                % best
                if ~strcmp(highlight_type, 'None')
                    if ~(sum(isnan(app.Etable_data(row_i, :))) == size(app.Etable_data, 2))
                        temp_data = app.Etable_data(row_i, :);
                        min_data = min(temp_data);
                        temp_idx = temp_data == min_data;
                        x = 1:length(temp_idx);
                        x = x(temp_idx);
                        for xx = 1:length(x)
                            app.EUITable.addStyle(high_color, 'cell', [row_i, x(xx)]);
                            app.EUITable.addStyle(font_bold, 'cell', [row_i, x(xx)]);
                        end
                    end
                end
                % worst
                if strcmp(highlight_type, 'Best&Worst')
                    isnan_temp = isnan(app.Etable_data(row_i, :));
                    if sum(isnan_temp)
                        x = 1:length(isnan_temp);
                        x = x(isnan_temp);
                        for xx = 1:length(x)
                            app.EUITable.addStyle(low_color, 'cell', [row_i, x(xx)]);
                        end
                    else
                        [~, index] = max(app.Etable_data(row_i, :));
                        app.EUITable.addStyle(low_color, 'cell', [row_i, index]);
                    end
                end
                drawnow;
            end
        end
        
        function EupdateTable(app)
            % update table in Experiment module
            
            app.EUITable.Data = {};
            if isempty(app.Edata) || isempty(app.Efitness)
                return;
            end
            app.EresetTable(app.Edata.algo_cell, app.Edata.prob_cell, app.Edata.tasks_num_list);
            switch app.EDataTypeDropDown.Value
                case 'Reps'
                    app.EupdateTableReps();
                case 'Obj'
                    app.EupdateTableObjective();
                    app.EupdateTableTest();
                case 'Score'
                    app.EupdateTableScore();
                case 'min(Obj)'
                    app.EupdateTableObjective();
                    app.EupdateTableTest();
                case 'Time used'
                    app.EupdateTableTimeUsed();
            end
            app.EupdateTableHighlight();
        end
        
        function EresetFormat(app)
            format_str = app.EDataFormatEditField.Value;
            if contains(app.EDataTypeDropDown.Value, 'Obj')
                switch app.EShowTypeDropDown.Value
                    case 'Mean'
                        format_str = '%.2e';
                    case 'Mean&Std'
                        format_str = '%.2e (%.2e)';
                    case 'Mean&CV'
                        format_str = '%.2e (%.2e)';
                    case 'Mean&CV&FR'
                        format_str = '%.2e / %.2e / %.2f';
                    case 'Std'
                        format_str = '%.2e';
                    case 'Median'
                        format_str = '%.2e';
                    case 'Best'
                        format_str = '%.2e';
                    case 'Worst'
                        format_str = '%.2e';
                    case 'CV'
                        format_str = '%.2e';
                    case 'FR'
                        format_str = '%.2f';
                end
            else
                format_str = '%.4f';
            end
            app.EDataFormatEditField.Value = format_str;
        end
        
        function EresetConvergenceProblemsDropDown(app)
            % reset convergence problems drop down in Experiment module
            
            switch app.EConvergenceTypeDropDown.Value
                case 'Obj'
                    prob_row_cell = {};
                    prob_row_index = {};
                    for prob = 1:length(app.Edata.prob_cell)
                        for task = 1:app.Edata.tasks_num_list(prob)
                            if app.Edata.tasks_num_list(prob) == 1
                                prob_row_cell = [prob_row_cell, app.Edata.prob_cell{prob}];
                            else
                                prob_row_cell = [prob_row_cell, [app.Edata.prob_cell{prob}, '-T', num2str(task)]];
                            end
                            prob_row_index = [prob_row_index, [prob, task]];
                        end
                    end
                    app.EProblemsDropDown.Items = prob_row_cell;
                    app.EProblemsDropDown.ItemsData = prob_row_index;
                    app.EProblemsDropDown.Value = [1 1];
                case 'min(Obj)'
                    app.EProblemsDropDown.Items = app.Edata.prob_cell;
                    app.EProblemsDropDown.ItemsData = 1:length(app.Edata.prob_cell);
                    app.EProblemsDropDown.Value = 1;
            end
        end
        
        function EupdateConvergenceAxes(app)
            % update convergence axes
            
            % clear axes
            cla(app.EConvergenceTrendUIAxes, 'reset');
            
            % check app.Edata
            if isempty(app.Edata)
                return;
            end
            
            % draw
            value = app.EProblemsDropDown.Value;
            xlim_max = 0;
            switch app.EConvergenceTypeDropDown.Value
                case 'Obj'
                    prob = value(1);
                    task = value(2);
                    tasks_num = app.Edata.tasks_num_list(prob);
                    for algo = 1:length(app.Edata.algo_cell)
                        convergence_task = app.Edata.result(prob, algo).convergence(task:tasks_num:end, :);
                        if isfield(app.Edata.result(prob, algo), 'convergence_cv')
                            convergence_cv_task = app.Edata.result(prob, algo).convergence_cv(task:tasks_num:end, :);
                            convergence_task(convergence_cv_task>0) = NaN;
                        end
                        convergence = nanmean(convergence_task, 1);
                        x_cell{algo} = 1:size(convergence,2);
                        % xlabel
                        if strcmp(app.EXLimTypeDropDown.Value, 'Evaluation')
                            x_cell{algo} = x_cell{algo} / length(x_cell{algo}) * app.Edata.sub_eva(prob);
                        elseif strcmp(app.EXLimTypeDropDown.Value, 'Generation')
                            x_cell{algo} = x_cell{algo} / length(x_cell{algo}) * (app.Edata.sub_eva(prob) / app.Edata.sub_pop(prob));
                        end
                        xlim_max = max(xlim_max, x_cell{algo}(end));
                        y_cell{algo} = convergence;
                    end
                case 'min(Obj)'
                    prob = value;
                    tasks_num = app.Edata.tasks_num_list(prob);
                    for algo = 1:length(app.Edata.algo_cell)
                        convergence_rep = [];
                        for rep = 1:app.Edata.reps
                            convergence_temp = app.Edata.result(prob, algo).convergence(1+(rep-1)*tasks_num:rep*tasks_num, :);
                            if isfield(app.Edata.result(prob, algo), 'convergence_cv')
                                convergence_cv_temp = app.Edata.result(prob, algo).convergence_cv(1+(rep-1)*tasks_num:rep*tasks_num, :);
                                convergence_temp(convergence_cv_temp>0) = NaN;
                            end
                            convergence_rep(rep, :) = min(convergence_temp, [], 1);
                        end
                        convergence = nanmean(convergence_rep, 1);
                        x_cell{algo} = 1:size(convergence,2);
                        % xlabel
                        if strcmp(app.EXLimTypeDropDown.Value, 'Evaluation')
                            x_cell{algo} = x_cell{algo} / length(x_cell{algo}) * app.Edata.sub_eva(prob) * tasks_num;
                        elseif strcmp(app.EXLimTypeDropDown.Value, 'Generation')
                            x_cell{algo} = x_cell{algo} / length(x_cell{algo}) * (app.Edata.sub_eva(prob) / app.Edata.sub_pop(prob));
                        end
                        xlim_max = max(xlim_max, x_cell{algo}(end));
                        y_cell{algo} = convergence;
                    end
            end
            
            if strcmp(app.EYLimTypeDropDown.Value, 'log(Objective Value)')
                for i = 1:length(y_cell)
                    y_cell{i} = log(y_cell{i});
                end
            end
            
            for i = 1:length(x_cell)
                if i > length(app.marker_list)
                    marker = '';
                else
                    marker = app.marker_list{i};
                end
                p = plot(app.EConvergenceTrendUIAxes, x_cell{i}, y_cell{i}, ['-', marker]);
                p.LineWidth = app.line_width;
                indices = round(length(y_cell{i})/app.EMarkerIndicesEditField.Value);
                p.MarkerIndices = indices:indices:length(y_cell{i})-round(indices/2);
                p.MarkerSize = app.marker_size;
                hold(app.EConvergenceTrendUIAxes, 'on');
            end
            
            xlim(app.EConvergenceTrendUIAxes, [1, xlim_max]);
            legend(app.EConvergenceTrendUIAxes, strrep(app.Edata.algo_cell, '_', '\_'));
            xlabel(app.EConvergenceTrendUIAxes, app.EXLimTypeDropDown.Value);
            ylabel(app.EConvergenceTrendUIAxes, app.EYLimTypeDropDown.Value);
            grid(app.EConvergenceTrendUIAxes, 'on');
        end
        
        function result = DcheckSplitData(app)
            % check and reproduce split data
            
            data_selected = app.DDataTree.SelectedNodes;
            app.Ddata_mark = [];
            data_num = 0;
            for i = 1:length(data_selected)
                if isa(data_selected(i).Parent, 'matlab.ui.container.Tree')
                    data_num = data_num + 1;
                    app.Ddata_mark(i) = 1;
                else
                    app.Ddata_mark(i) = 0;
                end
            end
            if data_num < 1
                msg = 'Select at least 1 data node to split';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                result = false;
                return;
            end
            
            result = true;
        end
        
        function result = DcheckMergeData(app)
            % check merge data num, pop size, iter num, eva num
            % select legal node
            
            data_selected = app.DDataTree.SelectedNodes;
            app.Ddata_mark = [];
            data_num = 0;
            for i = 1:length(data_selected)
                if isa(data_selected(i).Parent, 'matlab.ui.container.Tree')
                    data_num = data_num + 1;
                    app.Ddata_mark(i) = 1;
                else
                    app.Ddata_mark(i) = 0;
                end
            end
            if data_num < 2
                msg = 'Select at least 2 data node to merge';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                result = false;
                return;
            end
            
            data_selected = data_selected(app.Ddata_mark == 1);
            % check pop and evaluate
            sub_pop = data_selected(1).NodeData.sub_pop;
            sub_eva = data_selected(1).NodeData.sub_eva;
            for i = 2:data_num
                if ~isequal(data_selected(i).NodeData.sub_pop, sub_pop) || ...
                        ~isequal(data_selected(i).NodeData.sub_eva, sub_eva)
                msg = 'The data''s sub_pop or sub_eva not equal';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                result = false;
                return;
                end
            end
            result = true;
        end
        
        function result = DcheckMergeReps(app)
            % check merge reps
            
            data_num = sum(app.Ddata_mark);
            data_selected = app.DDataTree.SelectedNodes;
            data_selected = data_selected(app.Ddata_mark == 1);
            reps = data_selected(1).NodeData.reps;
            for i = 2:data_num
                if data_selected(i).NodeData.reps ~= reps
                    msg = 'The data''s reps not equal';
                    uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                    result = false;
                    return;
                end
            end
            result = true;
        end
        
        function result = DcheckMergeAlgorithms(app)
            % check merge algorithms
            
            data_num = sum(app.Ddata_mark);
            data_selected = app.DDataTree.SelectedNodes;
            data_selected = data_selected(app.Ddata_mark == 1);
            algo_cell = data_selected(1).NodeData.algo_cell;
            for i = 2:data_num
                if length(algo_cell) ~= length(data_selected(i).NodeData.algo_cell)
                    msg = 'The data''s algorithms not equal';
                    uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                    result = false;
                    return;
                end
                for algo = 1:length(algo_cell)
                    if ~strcmp(data_selected(i).NodeData.algo_cell{algo}, algo_cell{algo})
                        msg = 'The data''s algorithms not equal';
                        uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                        result = false;
                        return;
                    end
                end
            end
            result = true;
        end
        
        function result = DcheckMergeProblems(app)
            % check merge problems
            
            data_num = sum(app.Ddata_mark);
            data_selected = app.DDataTree.SelectedNodes;
            data_selected = data_selected(app.Ddata_mark == 1);
            prob_cell = data_selected(1).NodeData.prob_cell;
            for i = 2:data_num
                if length(prob_cell) ~= length(data_selected(i).NodeData.prob_cell)
                    msg = 'The data''s problems not equal';
                    uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                    result = false;
                    return;
                end
                for prob = 1:length(prob_cell)
                    if ~strcmp(data_selected(i).NodeData.prob_cell{prob}, prob_cell{prob})
                        msg = 'The data''s problems not equal';
                        uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                        result = false;
                        return;
                    end
                end
            end
            result = true;
        end
        
        function DputDataNode(app, name, data_save)
            % add data to tree in Data process module
            
            data_node = uitreenode(app.DDataTree);
            data_node.Text = name;
            data_node.NodeData = data_save;
            data_node.ContextMenu = app.DDataContextMenu;
            
            % child node
            reps_node = uitreenode(data_node);
            reps_node.Text = ['Reps: ', num2str(data_node.NodeData.reps)];
            reps_node.NodeData = reps_node.Text;
            reps_node.ContextMenu = app.DDataContextMenu;
            
            algo_node = uitreenode(data_node);
            algo_node.Text = 'Algorithms:';
            algo_node.NodeData = algo_node.Text;
            algo_node.ContextMenu = app.DDataContextMenu;
            for algo = 1:length(data_node.NodeData.algo_cell)
                algo_child_node = uitreenode(algo_node);
                algo_child_node.Text = data_node.NodeData.algo_cell{algo};
                algo_child_node.NodeData = algo_child_node.Text;
                algo_child_node.ContextMenu = app.DDataContextMenu;
            end
            
            prob_node = uitreenode(data_node);
            prob_node.Text = 'Problems:';
            prob_node.ContextMenu = app.DDataContextMenu;
            for prob = 1:length(data_node.NodeData.prob_cell)
                prob_child_node = uitreenode(prob_node);
                prob_child_node.Text = data_node.NodeData.prob_cell{prob};
                prob_child_node.NodeData = prob_child_node.Text;
                prob_child_node.ContextMenu = app.DDataContextMenu;
            end
            
            pop_node = uitreenode(data_node);
            text = num2str(data_node.NodeData.sub_pop', '%d, ');
            pop_node.Text = ['Population Size: ', text(1:end-1)];
            pop_node.NodeData = pop_node.Text;
            pop_node.ContextMenu = app.DDataContextMenu;
            
            eva_node = uitreenode(data_node);
            text = num2str(data_node.NodeData.sub_eva', '%d, ');
            eva_node.Text = ['Evaluation Max: ', text(1:end-1)];
            eva_node.NodeData = eva_node.Text;
            eva_node.ContextMenu = app.DDataContextMenu;
        end
        
        function DsaveData(app, data_save)
            % save data to folder in Data process module
            
            % check selected file name
            [file_name, dir_name] = uiputfile('data_save.mat');
            figure(app.MTOPlatformUIFigure);
            if file_name == 0
                return;
            end
            
            % save data
            save([dir_name, file_name], 'data_save');
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App startup function
            
            app.TloadAlgoProb();
            app.TupdateAlgorithm();
            app.TupdateProblem();
            app.TupdateUIAxes();
            app.EloadAlgoProb();
        end

        % Value changed function: TTaskTypeDropDown
        function TTaskTypeDropDownValueChanged(app, event)
            app.TloadAlgoProb();
        end

        % Value changed function: TSpecialTypeDropDown
        function TSpecialTypeDropDownValueChanged(app, event)
            app.TloadAlgoProb();
        end

        % Value changed function: TAlgorithmDropDown
        function TAlgorithmDropDownValueChanged(app, event)
            app.TupdateAlgorithm();
            app.Tdata = [];
            app.TupdateUIAxes();
        end

        % Drop down opening function: TAlgorithmDropDown
        function TAlgorithmDropDownOpening(app, event)
            app.TupdateAlgorithm();
            app.Tdata = [];
            app.TupdateUIAxes();
        end

        % Node text changed function: TAlgorithmTree
        function TAlgorithmTreeNodeTextChanged(app, event)
            % update algorithm obj parameter
            
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % this is algorithm name node
                node.NodeData.name = node.Text;
            else
                % this is parameter node
                parameter = {};
                % the first node text is parameter name, can't change
                for x = 1:2:length(node.Parent.Children)
                    node.Parent.Children(x).Text = node.Parent.Children(x).NodeData;
                end
                % the second node text is parameter value
                for x = 2:2:length(node.Parent.Children)
                    parameter = [parameter, node.Parent.Children(x).Text];
                end
                node.Parent.NodeData.setParameter(parameter);
            end
            app.Tdata = [];
            app.TupdateUIAxes();
        end

        % Value changed function: TProblemDropDown
        function TProblemDropDownValueChanged(app, event)
            app.TupdateProblem();
            app.Tdata = [];
            app.TupdateUIAxes();
        end

        % Drop down opening function: TProblemDropDown
        function TProblemDropDownOpening(app, event)
            app.TupdateProblem();
            app.Tdata = [];
            app.TupdateUIAxes();
        end

        % Node text changed function: TProblemTree
        function TProblemTreeNodeTextChanged(app, event)
            % update problem obj parameter
            
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % this is problem node
                node.NodeData.name = node.Text;
            else
                % this is parameter node
                parameter = {};
                % the first node text is parameter name, can't change
                for x = 1:2:length(node.Parent.Children)
                    node.Parent.Children(x).Text = node.Parent.Children(x).NodeData;
                end
                % the second node text is parameter value
                for x = 2:2:length(node.Parent.Children)
                    parameter = [parameter, node.Parent.Children(x).Text];
                end
                node.Parent.NodeData.setParameter(parameter);
            end
            
            app.Tdata = [];
            app.TupdateUIAxes();
        end

        % Value changed function: TShowTypeDropDown
        function TShowTypeDropDownValueChanged(app, event)
            app.TupdateUIAxes();
        end

        % Button pushed function: TStartButton
        function TStartButtonPushed(app, event)
            % start this test
            
            % off the start button
            app.TstartEnable(false);
            drawnow;
            
            % clear the temporary data
            app.Tdata = [];
            
            % read selected algorithms and problems
            algo_name = app.TAlgorithmTree.Children(1).Text;
            prob_name = app.TProblemTree.Children(1).Text;
            tasks_num = length(app.TProblemTree.Children(1).NodeData.getTasks());
            
            % run
            app.Tdata = singleRun(app.TAlgorithmTree.Children(1).NodeData, app.TProblemTree.Children(1).NodeData);
            para = app.TProblemTree.Children(1).NodeData.getRunParameterList();
            app.Tdata.sub_eva = para(2);
            app.Tdata.algo_name = algo_name;
            app.Tdata.prob_name = prob_name;
            app.Tdata.tasks_num = tasks_num;
            app.TupdateUIAxes();
            best_obj_temp = app.Tdata.convergence(:, end)';
            if isfield(app.Tdata, 'convergence_cv')
                best_cv_temp = app.Tdata.convergence_cv(:, end)';
                best_obj_temp(best_cv_temp>0) = NaN;
            end
            best_obj = num2str(best_obj_temp,'%.2e, ');
            app.Toutput([best_obj(1:end-1), ' (', prob_name, '-', algo_name, ')']);
            
            app.TstartEnable(true);
        end

        % Button pushed function: TResetButton
        function TResetButtonPushed(app, event)
            app.Tdata = [];
            app.TupdateUIAxes();
            app.TstartEnable(true);
        end

        % Button pushed function: TSaveButton
        function TSaveButtonPushed(app, event)
            % check selected file name
            filter = {'*.eps'; '*.pdf';'*.png';};
            [file_name, dir_name] = uiputfile(filter);
            if file_name == 0
                return;
            end
            exportgraphics(app.TUIAxes, [dir_name, file_name]);
        end

        % Value changed function: ETaskTypeDropDown
        function ETaskTypeDropDownValueChanged(app, event)
            app.EloadAlgoProb();
        end

        % Value changed function: ESpecialTypeDropDown
        function ESpecialTypeDropDownValueChanged(app, event)
            app.EloadAlgoProb();
        end

        % Context menu opening function: EAlgorithmsContextMenu
        function EAlgorithmsContextMenuOpening(app, event)
            % select all algorithms
            
            if ~isempty(app.EAlgorithmsListBox.Items)
                app.EAlgorithmsListBox.Value = app.EAlgorithmsListBox.ItemsData;
            end
        end

        % Button pushed function: EAlgorithmsAddButton
        function EAlgorithmsAddButtonPushed(app, event)
            % add selected algorithms to selected algorithms tree
            
            algo_selected = app.EAlgorithmsListBox.Value;
            for i= 1:length(algo_selected)
                eval(['algo_obj = ', algo_selected{i}, '("', strrep(algo_selected{i}, '_', '-'), '");']);
                algo_node = uitreenode(app.EAlgorithmsTree);
                algo_node.Text = algo_obj.getName();
                algo_node.NodeData = algo_obj;
                algo_node.ContextMenu = app.ESelectedAlgoContextMenu;
                
                % child parameter node
                parameter = algo_obj.getParameter();
                for p = 1:2:length(parameter)
                    para_name_node = uitreenode(algo_node);
                    para_name_node.Text = ['[ ', parameter{p}, ' ]'];
                    para_name_node.NodeData = para_name_node.Text;
                    para_name_node.ContextMenu = app.ESelectedAlgoContextMenu;
                    para_value_node = uitreenode(algo_node);
                    para_value_node.Text = parameter{p+1};
                    para_value_node.ContextMenu = app.ESelectedAlgoContextMenu;
                end
            end
            
            % collapse other node and expand this node
            all_node = algo_node.Parent.Children;
            for i = 1:length(all_node)
                collapse(all_node(i));
            end
            expand(algo_node);
        end

        % Menu selected function: ProblemsSelectAllMenu
        function EProblemsContextMenuOpening(app, event)
            % select all problems
            
            if ~isempty(app.EProblemsListBox.Items)
                app.EProblemsListBox.Value = app.EProblemsListBox.ItemsData;
            end
        end

        % Button pushed function: EProblemsAddButton
        function EProblemsAddButtonPushed(app, event)
            % add selected problems to selected problems tree
            
            prob_selected = app.EProblemsListBox.Value;
            for i= 1:length(prob_selected)
                eval(['prob_obj = ', prob_selected{i}, '("', strrep(prob_selected{i}, '_', '-'), '");']);
                prob_node = uitreenode(app.EProblemsTree);
                prob_node.Text = prob_obj.getName();
                prob_node.NodeData = prob_obj;
                prob_node.ContextMenu = app.ESelectedProbContextMenu;
                
                % child parameter node
                parameter = prob_obj.getParameter();
                for p = 1:2:length(parameter)
                    para_name_node = uitreenode(prob_node);
                    para_name_node.Text = ['[ ', parameter{p}, ' ]'];
                    para_name_node.NodeData = para_name_node.Text;
                    para_name_node.ContextMenu = app.ESelectedProbContextMenu;
                    para_value_node = uitreenode(prob_node);
                    para_value_node.Text = parameter{p+1};
                    para_value_node.ContextMenu = app.ESelectedProbContextMenu;
                end
            end
            
            % collapse other node and expand this node
            all_node = prob_node.Parent.Children;
            for i = 1:length(all_node)
                collapse(all_node(i));
            end
            expand(prob_node);
        end

        % Button pushed function: EStartButton
        function EStartButtonPushed(app, event)
            % start this experiment
            
            % check selected
            algo_num = length(app.EAlgorithmsTree.Children);
            prob_num = length(app.EProblemsTree.Children);
            if algo_num == 0
                msg = 'Please select the Algorithm first';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                app.EstartEnable(true);
                return;
            end
            if prob_num == 0
                msg = 'Please select the Problem first';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                app.EstartEnable(true);
                return;
            end
            
            % off the start button
            app.EstartEnable(false);
            app.Estop_flag = false;
            
            % read selected algorithms and problems
            algo_cell = {};
            for algo = 1:algo_num
                algo_cell{algo} = app.EAlgorithmsTree.Children(algo).Text;
            end
            prob_cell = {};
            for prob = 1:prob_num
                prob_cell{prob} = app.EProblemsTree.Children(prob).Text;
                tasks_num_list(prob) = length(app.EProblemsTree.Children(prob).NodeData.getTasks());
            end
            
            % clear the temporary data
            app.Edata = [];
            app.EDataTypeDropDown.Value = 'Reps';
            
            % initialize data
            for algo = 1:algo_num
                for prob = 1:prob_num
                    app.Edata.result(prob, algo).clock_time = 0;
                    app.Edata.result(prob, algo).convergence = [];
                    app.Edata.result(prob, algo).convergence_cv = [];
                    app.Edata.result(prob, algo).bestX = {};
                end
            end
            app.Edata.reps = 0;
            for prob = 1:prob_num
                run_parameter_list = app.EProblemsTree.Children(prob).NodeData.getRunParameterList();
                app.Edata.sub_pop(prob) = run_parameter_list(1);
                app.Edata.sub_eva(prob) = run_parameter_list(2);
            end
            app.Edata.sub_pop = app.Edata.sub_pop';
            app.Edata.sub_eva = app.Edata.sub_eva';
            app.Edata.algo_cell = algo_cell;
            app.Edata.prob_cell = prob_cell';
            app.Edata.tasks_num_list = tasks_num_list';
            
            % reset table and convergence
            app.Etable_reps = zeros(length(prob_cell), length(algo_cell));
            app.EupdateTableReps();
            app.EresetTable(algo_cell, prob_cell, tasks_num_list);
            app.EresetTableAlgorithmDropDown(algo_cell);
            cla(app.EConvergenceTrendUIAxes, 'reset');
            
            % main experiment loop
            tStart = tic;
            if app.EParallelDropDown.Value == 0
                for rep = 1:app.ERepsEditField.Value
                    for prob = 1:prob_num
                        for algo = 1:algo_num
                            % check pause and stop
                            app.EcheckPauseStopStatus();
                            % run
                            tStart = tic;
                            data = singleRun(app.EAlgorithmsTree.Children(algo).NodeData, app.EProblemsTree.Children(prob).NodeData);
                            if ~isfield(data, 'convergence_cv')
                                data.convergence_cv = zeros(size(data.convergence));
                            end
                            app.Edata.result(prob, algo).clock_time = app.Edata.result(prob, algo).clock_time + toc(tStart);
                            if app.Edata.result(prob, algo).convergence
                                % convergence
                                gen_old = size(app.Edata.result(prob, algo).convergence, 2);
                                gen_new = size(data.convergence, 2);
                                if gen_old < gen_new
                                    app.Edata.result(prob, algo).convergence = [app.Edata.result(prob, algo).convergence, repmat(app.Edata.result(prob, algo).convergence(:, gen_old), 1, gen_new-gen_old)];
                                else
                                    data.convergence = [data.convergence, repmat(data.convergence(:, gen_new), 1, gen_old-gen_new)];
                                end
                                app.Edata.result(prob, algo).convergence = [app.Edata.result(prob, algo).convergence; data.convergence];
                                % convergence_cv
                                gen_old = size(app.Edata.result(prob, algo).convergence_cv, 2);
                                gen_new = size(data.convergence_cv, 2);
                                if gen_old < gen_new
                                    app.Edata.result(prob, algo).convergence_cv = [app.Edata.result(prob, algo).convergence_cv, repmat(app.Edata.result(prob, algo).convergence_cv(:, gen_old), 1, gen_new-gen_old)];
                                else
                                    data.convergence_cv = [data.convergence_cv, repmat(data.convergence_cv(:, gen_new), 1, gen_old-gen_new)];
                                end
                                app.Edata.result(prob, algo).convergence_cv = [app.Edata.result(prob, algo).convergence_cv; data.convergence_cv];
                            else
                                app.Edata.result(prob, algo).convergence = data.convergence;
                                app.Edata.result(prob, algo).convergence_cv = data.convergence_cv;
                            end
                            app.Edata.result(prob, algo).bestX = [app.Edata.result(prob, algo).bestX; data.bestX];
                            app.Etable_reps(prob, algo) = rep;
                            app.EupdateTableReps();
                        end
                    end
                    app.Edata.reps = rep;
                    app.EcalculatePre();
                    app.EupdateTable();
                    app.EresetConvergenceProblemsDropDown();
                    app.EupdateConvergenceAxes();
                end
            else
                % start parallel
                reps = app.ERepsEditField.Value;
                for prob = 1:prob_num
                    for algo = 1:algo_num
                        algo_obj = app.EAlgorithmsTree.Children(algo).NodeData;
                        prob_obj = app.EProblemsTree.Children(prob).NodeData;
                        convergence = {};
                        convergence_cv = {};
                        bestX = {};
                        par_tool = Par(reps);
                        parfor rep = 1:reps
                            Par.tic
                            data = singleRun(algo_obj, prob_obj);
                            par_tool(rep) = Par.toc;
                            convergence = [convergence; {data.convergence}];
                            if ~isfield(data, 'convergence_cv')
                                data.convergence_cv = zeros(size(data.convergence));
                            end
                            convergence_cv = [convergence_cv; {data.convergence_cv}];
                            bestX = [bestX; data.bestX];
                        end
                        app.Edata.result(prob, algo).convergence = convergence{1};
                        app.Edata.result(prob, algo).convergence_cv = convergence_cv{1};
                        for rep = 2:reps
                            % convergence
                            gen_old = size(app.Edata.result(prob, algo).convergence, 2);
                            gen_new = size(convergence{rep}, 2);
                            if gen_old < gen_new
                                app.Edata.result(prob, algo).convergence = [app.Edata.result(prob, algo).convergence, repmat(app.Edata.result(prob, algo).convergence(:, gen_old), 1, gen_new-gen_old)];
                            else
                                convergence{rep} = [convergence{rep}, repmat(convergence{rep}(:, gen_new), 1, gen_old-gen_new)];
                            end
                            app.Edata.result(prob, algo).convergence = [app.Edata.result(prob, algo).convergence; convergence{rep}];
                            % convergence_cv
                            gen_old = size(app.Edata.result(prob, algo).convergence_cv, 2);
                            gen_new = size(convergence_cv{rep}, 2);
                            if gen_old < gen_new
                                app.Edata.result(prob, algo).convergence_cv = [app.Edata.result(prob, algo).convergence_cv, repmat(app.Edata.result(prob, algo).convergence_cv(:, gen_old), 1, gen_new-gen_old)];
                            else
                                convergence_cv{rep} = [convergence_cv{rep}, repmat(convergence_cv{rep}(:, gen_new), 1, gen_old-gen_new)];
                            end
                            app.Edata.result(prob, algo).convergence_cv = [app.Edata.result(prob, algo).convergence_cv; convergence_cv{rep}];
                        end
                        app.Edata.result(prob, algo).clock_time = sum([par_tool.ItStop]- [par_tool.ItStart]);
                        app.Edata.result(prob, algo).bestX = bestX;
                        app.Etable_reps(prob, algo) = reps;
                        app.EupdateTableReps();
                        app.EcheckPauseStopStatus();
                    end
                end
                app.Edata.reps = reps;
                app.EcalculatePre();
                app.EupdateTable();
                app.EresetConvergenceProblemsDropDown();
                app.EupdateConvergenceAxes();
            end
            
            tEnd = toc(tStart);
            msg = ['All Use Time: ', char(duration([0, 0, tEnd]))];
            uiconfirm(app.MTOPlatformUIFigure, msg, 'success', 'Icon', 'success');
            
            app.EstartEnable(true);
        end

        % Button pushed function: EPauseButton
        function EPauseButtonPushed(app, event)
            % pause or resume this experiment
            
            if strcmp(app.EPauseButton.Text, 'Pause')
                app.EStopButton.Enable = 'off';
                app.EPauseButton.Text = 'Resume';
            else
                app.EStopButton.Enable = 'on';
                app.EPauseButton.Text = 'Pause';
            end
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            % stop this experiment
            
            app.EstartEnable(true);
            app.Estop_flag = true;
        end

        % Context menu opening function: ESelectedAlgoContextMenu
        function ESelectedAlgoContextMenuOpening(app, event)
            % select all selected algorithms
            
            if ~isempty(app.EAlgorithmsTree.Children)
                app.EAlgorithmsTree.SelectedNodes = app.EAlgorithmsTree.Children;
            end
        end

        % Button pushed function: EAlgorithmsDelButton
        function EAlgorithmsDelButtonPushed(app, event)
            % delete selected algorithms from algorithms tree
            
            algo_selected = app.EAlgorithmsTree.SelectedNodes;
            if isempty(algo_selected)
                msg = 'Select Algorithm node in tree first';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
            end
            
            for i = 1:length(algo_selected)
                if isa(algo_selected(i).Parent, 'matlab.ui.container.Tree')
                    algo_selected(i).delete;
                end
            end
        end

        % Node text changed function: EAlgorithmsTree
        function EAlgorithmsTreeNodeTextChanged(app, event)
            % update algorithm obj parameter
            
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % this is algorithm name node
                node.NodeData.name = node.Text;
            else
                % this is parameter node
                parameter = {};
                % the first node text is parameter name, can't change
                for x = 1:2:length(node.Parent.Children)
                    node.Parent.Children(x).Text = node.Parent.Children(x).NodeData;
                end
                % the second node text is parameter value
                for x = 2:2:length(node.Parent.Children)
                    parameter = [parameter, node.Parent.Children(x).Text];
                end
                node.Parent.NodeData.setParameter(parameter);
            end
        end

        % Menu selected function: SelectedProbSelectAllMenu
        function ESelectedProbContextMenuOpening(app, event)
            % select all selected problems
            
            if ~isempty(app.EProblemsTree.Children)
                app.EProblemsTree.SelectedNodes = app.EProblemsTree.Children;
            end
        end

        % Button pushed function: EProblemsDelButton
        function EProblemsDelButtonPushed(app, event)
            % delete selected problems from problems tree
            
            prob_selected = app.EProblemsTree.SelectedNodes;
            if isempty(prob_selected)
                msg = 'Select Problem node in tree first';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
            end
            
            for i = 1:length(prob_selected)
                if isa(prob_selected(i).Parent, 'matlab.ui.container.Tree')
                    prob_selected(i).delete;
                end
            end
        end

        % Node text changed function: EProblemsTree
        function EProblemsTreeNodeTextChanged(app, event)
            % update problem obj parameter
            
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % this is problem node
                node.NodeData.name = node.Text;
            else
                % this is parameter node
                parameter = {};
                % the first node text is parameter name, can't change
                for x = 1:2:length(node.Parent.Children)
                    node.Parent.Children(x).Text = node.Parent.Children(x).NodeData;
                end
                % the second node text is parameter value
                for x = 2:2:length(node.Parent.Children)
                    parameter = [parameter, node.Parent.Children(x).Text];
                end
                node.Parent.NodeData.setParameter(parameter);
            end
        end

        % Button pushed function: ESaveDataButton
        function ESaveDataButtonPushed(app, event)
            % save data to folder
            
            % check data
            if isempty(app.Edata)
                msg = 'Please run experiment first';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                return;
            end
            
            % check selected file name
            [file_name, dir_name] = uiputfile('data_save.mat');
            figure(app.MTOPlatformUIFigure);
            if file_name == 0
                return;
            end
            
            % save data
            data_save = app.Edata;
            
            % fix convergence bug
            for prob = 1:length(data_save.prob_cell)
                for algo = 1:length(data_save.algo_cell)
                    idx = 1:data_save.tasks_num_list(prob) * data_save.reps;
                    data_save.result(prob, algo).convergence = data_save.result(prob, algo).convergence(idx, :);
                    data_save.result(prob, algo).convergence_cv = data_save.result(prob, algo).convergence_cv(idx, :);
                end
            end
            
            save([dir_name, file_name], 'data_save');
        end

        % Value changed function: EDataFormatEditField
        function EDataFormatEditFieldValueChanged(app, event)
            app.EupdateTable();
        end

        % Value changed function: EDataTypeDropDown
        function EDataTypeDropDownValueChanged(app, event)
            app.EresetFormat();
            app.EupdateTable();
        end

        % Value changed function: EShowTypeDropDown
        function EShowTypeDropDownValueChanged(app, event)
            app.EresetFormat();
            app.EupdateTable();
        end

        % Value changed function: ETestTypeDropDown
        function ETestTypeDropDownValueChanged(app, event)
            app.EupdateTable();
        end

        % Value changed function: EAlgorithmDropDown
        function EAlgorithmDropDownValueChanged(app, event)
            app.EupdateTable();
        end

        % Value changed function: EHighlightTypeDropDown
        function EHighlightTypeDropDownValueChanged(app, event)
            app.EupdateTableHighlight();
        end

        % Value changed function: EConvergenceTypeDropDown
        function EConvergenceTypeDropDownValueChanged(app, event)
            app.EresetConvergenceProblemsDropDown();
            app.EupdateConvergenceAxes();
        end

        % Value changed function: EYLimTypeDropDown
        function EYLimTypeDropDownValueChanged(app, event)
            app.EupdateConvergenceAxes();
        end

        % Value changed function: EXLimTypeDropDown
        function EXLimTypeDropDownValueChanged(app, event)
            app.EupdateConvergenceAxes();
        end

        % Value changed function: EProblemsDropDown
        function EProblemsDropDownValueChanged(app, event)
            app.EupdateConvergenceAxes();
        end

        % Button pushed function: ELoadDataButton
        function ELoadDataButtonPushed(app, event)
            % load data from file
            
            % select mat file
            [file_name, pathname] = uigetfile('*.mat', 'Select Data', './');
            figure(app.MTOPlatformUIFigure);
            
            % check selected ile_name
            if file_name == 0
                return;
            end
            
            % load data to app's parameter
            load([pathname, file_name], 'data_save');
            app.Edata = data_save;
            app.Etable_reps = app.Edata.reps * ones([length(app.Edata.prob_cell), length(app.Edata.algo_cell)]);
            app.EresetTableAlgorithmDropDown(app.Edata.algo_cell);
            app.EcalculatePre();
            app.EupdateTable();
            app.EresetConvergenceProblemsDropDown();
            app.EupdateConvergenceAxes();
        end

        % Button pushed function: ESaveTableButton
        function ESaveTableButtonPushed(app, event)
            % save table
            
            % check selected file name
            filter = {'*.tex'; '*.xlsx';'*.csv';};
            [file_name, dir_name] = uiputfile(filter);
            % figure(app.MTOPlatformUIFigure);
            if file_name == 0
                return;
            end
            if contains(file_name, 'tex')
                hl = zeros(size(app.EUITable.Data));
                for row_i = 1:size(app.Etable_data, 1)
                    if ~(sum(isnan(app.Etable_data(row_i, :))) == size(app.Etable_data, 2))
                        temp_data = app.Etable_data(row_i, :);
                        min_data = min(temp_data);
                        temp_idx = temp_data == min_data;
                        x = 1:length(temp_idx);
                        x = x(temp_idx);
                        for xx = 1:length(x)
                            hl(row_i, x(xx)) = 1;
                        end
                    end
                end
                input.data = app.EUITable.Data;
                input.hl = hl;
                input.tableColLabels = app.EUITable.ColumnName(1:size(input.data, 2));
                input.tableRowLabels = app.EUITable.RowName(1:size(input.data, 1))';
                input.tableColumnAlignment = 'c';
                input.tableBorders = 0;
                input.dataNanString = '-';
                input.booktabs = 1;
                latex = latexTable(input);
                fid=fopen([dir_name, file_name],'w');
                [nrows, ncols] = size(latex);
                for row = 1:nrows
                    fprintf(fid,'%s\n',latex{row,:});
                end
                fclose(fid);
            else
                row_name = app.EUITable.RowName(1:size(app.EUITable.Data, 1));
                column_name = app.EUITable.ColumnName(1:size(app.EUITable.Data, 2))';
                cell_out = [[{''}; row_name], [column_name; app.EUITable.Data]];
                writecell(cell_out, [dir_name, file_name]);
            end
        end

        % Button pushed function: ESaveAllFigureButton
        function ESaveAllFigureButtonPushed(app, event)
            % save figure to folder
            
            % check data
            if isempty(app.Edata)
                return;
            end
            
            % check selected dir name
            dir_name = uigetdir('./', 'Select save path');
            figure(app.MTOPlatformUIFigure);
            if dir_name == 0
                return;
            end
            
            % save figure
            fig_dir_name = [dir_name, '/Figure/'];
            mkdir(fig_dir_name);
            draw_obj = drawFigure;
            switch app.EConvergenceTypeDropDown.Value
                case 'Obj'
                    for prob = 1:length(app.Edata.prob_cell)
                        tasks_num = app.Edata.tasks_num_list(prob);
                        for task = 1:tasks_num
                            for algo = 1:length(app.Edata.algo_cell)
                                convergence_task = app.Edata.result(prob, algo).convergence(task:tasks_num:end, :);
                                if isfield(app.Edata.result(prob, algo), 'convergence_cv')
                                    convergence_cv_task = app.Edata.result(prob, algo).convergence_cv(task:tasks_num:end, :);
                                    convergence_task(convergence_cv_task>0)= NaN;
                                end
                                convergence = mean(convergence_task, 1);
                                x_cell{algo} = 1:size(convergence,2);
                                x_cell{algo} = x_cell{algo} / length(x_cell{algo}) * app.Edata.sub_eva(prob);
                                y_cell{algo} = convergence;
                            end
                            switch app.EYLimTypeDropDown.Value
                                case 'log(Objective Value)'
                                    for i = 1:length(y_cell)
                                        y_cell{i} = log(y_cell{i});
                                    end
                            end
                            draw_obj.setXY(x_cell, y_cell);
                            draw_obj.setXYlabel('Evaluation', app.EYLimTypeDropDown.Value);
                            draw_obj.setLegend(app.Edata.algo_cell);
                            if app.Edata.tasks_num_list(prob) == 1
                                draw_obj.setTitle(app.Edata.prob_cell{prob});
                            else
                                draw_obj.setTitle([app.Edata.prob_cell{prob}, ' T', num2str(task)]);
                            end
                            draw_obj.setSaveDir(fig_dir_name);
                            draw_obj.setFigureType(app.EFigureTypeDropDown.Value);
                            draw_obj.setMarkerIndices(app.EMarkerIndicesEditField.Value);
                            draw_obj.save();
                        end
                    end
                    
                case 'min(Obj)'
                    for prob = 1:length(app.Edata.prob_cell)
                        tasks_num = app.Edata.tasks_num_list(prob);
                        for algo = 1:length(app.Edata.algo_cell)
                            convergence_rep = [];
                            for rep = 1:app.Edata.reps
                                convergence_temp = app.Edata.result(prob, algo).convergence(1+(rep-1)*tasks_num:rep*tasks_num, :);
                                if isfield(app.Edata.result(prob, algo), 'convergence_cv')
                                    convergence_cv_temp = app.Edata.result(prob, algo).convergence_cv(1+(rep-1)*tasks_num:rep*tasks_num, :);
                                    convergence_temp(convergence_cv_temp>0)= NaN;
                                end
                                convergence_rep(rep, :) = min(convergence_temp, [], 1);
                            end
                            convergence = mean(convergence_rep, 1);
                            x_cell{algo} = 1:size(convergence,2);
                            x_cell{algo} = x_cell{algo} / length(x_cell{algo}) * app.Edata.sub_eva(prob) * tasks_num;
                            y_cell{algo} = convergence;
                        end
                        switch app.EYLimTypeDropDown.Value
                            case 'log(Objective Value)'
                                for i = 1:length(y_cell)
                                    y_cell{i} = log(y_cell{i});
                                end
                        end
                        draw_obj.setXY(x_cell, y_cell);
                        draw_obj.setXYlabel('Evaluation', app.EYLimTypeDropDown.Value);
                        draw_obj.setLegend(app.Edata.algo_cell);
                        draw_obj.setTitle(app.Edata.prob_cell{prob});
                        draw_obj.setSaveDir(fig_dir_name);
                        draw_obj.setFigureType(app.EFigureTypeDropDown.Value);
                        draw_obj.setMarkerIndices(app.EMarkerIndicesEditField.Value);
                        draw_obj.save();
                    end
            end
        end

        % Value changed function: EMarkerIndicesEditField
        function EMarkerIndicesEditFieldValueChanged(app, event)
            app.EupdateConvergenceAxes()
        end

        % Context menu opening function: DDataContextMenu
        function DDataContextMenuOpening(app, event)
            % select all data
            
            if ~isempty(app.DDataTree.Children)
                app.DDataTree.SelectedNodes = app.DDataTree.Children;
            end
        end

        % Button pushed function: DLoadDataButton
        function DLoadDataButtonPushed(app, event)
            % load data from mat files
            
            % select mat file
            file_name_list = {};
            [file_name, pathname] = uigetfile('*.mat', 'select the data mat', './', 'MultiSelect', 'on');
            figure(app.MTOPlatformUIFigure);
            file_name_list = [file_name_list, file_name];
            
            % check selected file_name
            if file_name_list{1} == 0
                return;
            end
            
            %load data mat files
            for i = 1:length(file_name_list)
                load([pathname, file_name_list{i}], 'data_save');
                % extract necessary field
                if isfield(data_save.result, 'bestX')
                    data_save.result = rmfield(data_save.result, 'bestX');
                end
                app.DputDataNode(file_name_list{i}(1:end-4), data_save);
                drawnow;
            end
        end

        % Button pushed function: DDeleteDataButton
        function DDeleteDataButtonPushed(app, event)
            % delete selected data from tree
            
            data_selected = app.DDataTree.SelectedNodes;
            data_mark = [];
            data_num = 0;
            for i = 1:length(data_selected)
                if isa(data_selected(i).Parent, 'matlab.ui.container.Tree')
                    data_num = data_num + 1;
                    data_mark(i) = 1;
                else
                    data_mark(i) = 0;
                end
            end
            if data_num == 0
                msg = 'Select data node in tree first';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
            end
            
            data_selected = data_selected(data_mark == 1);
            for i = 1:length(data_selected)
                data_selected(i).delete();
                drawnow;
            end
        end

        % Button pushed function: DSaveDataButton
        function DSaveDataButtonPushed(app, event)
            % save selected data from tree
            
            data_selected = app.DDataTree.SelectedNodes;
            data_mark = [];
            data_num = 0;
            for i = 1:length(data_selected)
                if isa(data_selected(i).Parent, 'matlab.ui.container.Tree')
                    data_num = data_num + 1;
                    data_mark(i) = 1;
                else
                    data_mark(i) = 0;
                end
            end
            if data_num == 0
                msg = 'Select data node in tree first';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
            end
            
            data_selected = data_selected(data_mark == 1);
            for i = 1:length(data_selected)
                app.DsaveData(data_selected(i).NodeData);
            end
        end

        % Button pushed function: DRepsSplitButton
        function DRepsSplitButtonPushed(app, event)
            % split reps
            
            if ~app.DcheckSplitData()
                return;
            end
            
            % split
            data_selected = app.DDataTree.SelectedNodes;
            data_selected = data_selected(app.Ddata_mark == 1);
            
            for i = 1:length(data_selected)
                if data_selected(i).NodeData.reps <= 1
                    msg = ['The ', data_selected(i).Text, '''s reps <= 1'];
                    uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                    continue;
                end
                for rep = 1:data_selected(i).NodeData.reps
                    data_save.reps = 0;
                    data_save.tasks_num_list = data_selected(i).NodeData.tasks_num_list;
                    data_save.sub_pop = data_selected(i).NodeData.sub_pop;
                    data_save.sub_eva = data_selected(i).NodeData.sub_eva;
                    data_save.algo_cell = data_selected(i).NodeData.algo_cell;
                    data_save.prob_cell = data_selected(i).NodeData.prob_cell;
                    data_save.reps = 1;
                    data_save.result = [];
                    for prob = 1:length(data_save.prob_cell)
                        task_num = data_selected(i).NodeData.tasks_num_list(prob);
                        for algo = 1:length(data_save.algo_cell)
                            data_save.result(prob, algo).clock_time = data_selected(i).NodeData.result(prob, algo).clock_time / data_selected(i).NodeData.reps;
                            data_save.result(prob, algo).convergence = data_selected(i).NodeData.result(prob, algo).convergence((rep-1)*task_num+1 : rep*task_num, :);
                            if isfield(data_selected(i).NodeData.result(prob, algo), 'convergence_cv')
                                data_save.result(prob, algo).convergence_cv = data_selected(i).NodeData.result(prob, algo).convergence_cv((rep-1)*task_num+1 : rep*task_num, :);
                            end
                            if isfield(data_selected(i).NodeData.result(prob, algo), 'bestX')
                                data_save.result(prob, algo).bestX = data_selected(i).NodeData.result(prob, algo).bestX(rep, :);
                            end
                        end
                    end
                    app.DputDataNode([data_selected(i).Text, ' (Split Rep: ', num2str(rep), ')'], data_save);
                    drawnow;
                end
            end
        end

        % Button pushed function: DAlgorithmsSplitButton
        function DAlgorithmsSplitButtonPushed(app, event)
            % split algorithms
            
            if ~app.DcheckSplitData()
                return;
            end
            
            % split
            data_selected = app.DDataTree.SelectedNodes;
            data_selected = data_selected(app.Ddata_mark == 1);
            
            for i = 1:length(data_selected)
                if length(data_selected(i).NodeData.algo_cell) <= 1
                    msg = ['The ', data_selected(i).Text, '''s algorithms <= 1'];
                    uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                    continue;
                end
                for algo = 1:length(data_selected(i).NodeData.algo_cell)
                    data_save.reps = data_selected(i).NodeData.reps;
                    data_save.tasks_num_list = data_selected(i).NodeData.tasks_num_list;
                    data_save.sub_pop = data_selected(i).NodeData.sub_pop;
                    data_save.sub_eva = data_selected(i).NodeData.sub_eva;
                    data_save.prob_cell = data_selected(i).NodeData.prob_cell;
                    data_save.algo_cell = data_selected(i).NodeData.algo_cell(algo);
                    data_save.result = data_selected(i).NodeData.result(:, algo);
                    
                    app.DputDataNode([data_selected(i).Text, ' (Split Algorithm: ', data_save.algo_cell{1}, ')'], data_save);
                    drawnow;
                end
            end
        end

        % Button pushed function: DProblemsSplitButton
        function DProblemsSplitButtonPushed(app, event)
            % split algorithms
            
            if ~app.DcheckSplitData()
                return;
            end
            
            % split
            data_selected = app.DDataTree.SelectedNodes;
            data_selected = data_selected(app.Ddata_mark == 1);
            
            for i = 1:length(data_selected)
                if length(data_selected(i).NodeData.prob_cell) <= 1
                    msg = ['The ', data_selected(i).Text, '''s problems <= 1'];
                    uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                    continue;
                end
                for prob = 1:length(data_selected(i).NodeData.prob_cell)
                    data_save.reps = data_selected(i).NodeData.reps;
                    data_save.sub_pop = [[], data_selected(i).NodeData.sub_pop(prob)];
                    data_save.sub_eva = [[], data_selected(i).NodeData.sub_eva(prob)];
                    data_save.algo_cell = data_selected(i).NodeData.algo_cell;
                    data_save.prob_cell = data_selected(i).NodeData.prob_cell(prob);
                    data_save.tasks_num_list = [[], data_selected(i).NodeData.tasks_num_list(prob)];
                    data_save.result = data_selected(i).NodeData.result(prob, :);
                    
                    app.DputDataNode([data_selected(i).Text, ' (Split Problem: ', data_save.prob_cell{1}, ')'], data_save);
                    drawnow;
                end
            end
        end

        % Button pushed function: DRepsMergeButton
        function DRepsMergeButtonPushed(app, event)
            % merge reps, with same pop, evaluate, algorithms and problems
            
            if ~app.DcheckMergeData() || ~app.DcheckMergeAlgorithms() || ~app.DcheckMergeProblems()
                return;
            end
            
            % merge
            data_selected = app.DDataTree.SelectedNodes;
            data_selected = data_selected(app.Ddata_mark == 1);
            data_save.reps = 0;
            data_save.tasks_num_list = data_selected(1).NodeData.tasks_num_list;
            data_save.sub_pop = data_selected(1).NodeData.sub_pop;
            data_save.sub_eva = data_selected(1).NodeData.sub_eva;
            data_save.algo_cell = data_selected(1).NodeData.algo_cell;
            data_save.prob_cell = data_selected(1).NodeData.prob_cell;
            for prob = 1:length(data_save.prob_cell)
                for algo = 1:length(data_save.algo_cell)
                    data_save.result(prob, algo).clock_time = 0;
                    data_save.result(prob, algo).convergence = [];
                    data_save.result(prob, algo).convergence_cv = [];
                    data_save.result(prob, algo).bestX = {};
                end
            end
            for i = 1:length(data_selected)
                data_save.reps = data_save.reps + data_selected(i).NodeData.reps;
                for prob = 1:length(data_save.prob_cell)
                    for algo = 1:length(data_save.algo_cell)
                        data_save.result(prob, algo).clock_time = data_save.result(prob, algo).clock_time + data_selected(i).NodeData.result(prob, algo).clock_time;
                        data_save.result(prob, algo).convergence = [data_save.result(prob, algo).convergence; data_selected(i).NodeData.result(prob, algo).convergence];
                        if isfield(data_selected(i).NodeData.result(prob, algo), 'convergence_cv')
                            data_save.result(prob, algo).convergence_cv = [data_save.result(prob, algo).convergence_cv; data_selected(i).NodeData.result(prob, algo).convergence_cv];
                        else
                            data_save.result(prob, algo).convergence_cv = [data_save.result(prob, algo).convergence_cv; zeros(size(data_selected(i).NodeData.result(prob, algo).convergence))];
                        end
                        if isfield(data_selected(i).NodeData.result(prob, algo), 'bestX')
                            data_save.result(prob, algo).bestX = [data_save.result(prob, algo).bestX; data_selected(i).NodeData.result(prob, algo).bestX];
                        end
                    end
                end
            end
            
            app.DputDataNode('data (Merge Reps)', data_save);
            drawnow;
        end

        % Button pushed function: DAlgorithmsMergeButton
        function DAlgorithmsMergeButtonPushed(app, event)
            % merge algorithms, with same pop, evaluate, reps and problems
            
            if ~app.DcheckMergeData() || ~app.DcheckMergeReps() || ~app.DcheckMergeProblems()
                return;
            end
            
            % merge
            data_selected = app.DDataTree.SelectedNodes;
            data_selected = data_selected(app.Ddata_mark == 1);
            data_save.reps = data_selected(1).NodeData.reps;
            data_save.tasks_num_list = data_selected(1).NodeData.tasks_num_list;
            data_save.sub_pop = data_selected(1).NodeData.sub_pop;
            data_save.sub_eva = data_selected(1).NodeData.sub_eva;
            data_save.prob_cell = data_selected(1).NodeData.prob_cell;
            data_save.algo_cell = {};
            for i = 1:length(data_selected)
                algo_start = length(data_save.algo_cell) + 1;
                data_save.algo_cell = [data_save.algo_cell, data_selected(i).NodeData.algo_cell];
                algo_end = length(data_save.algo_cell);
                data_save.result(:, algo_start:algo_end) = data_selected(i).NodeData.result;
            end
            
            app.DputDataNode('data (Merge Algorithms)', data_save);
            drawnow;
        end

        % Button pushed function: DProblemsMergeButton
        function DProblemsMergeButtonPushed(app, event)
            % merge problems, with same pop, evaluate, reps and algorithms
            
            data_selected = app.DDataTree.SelectedNodes;
            app.Ddata_mark = [];
            data_num = 0;
            for i = 1:length(data_selected)
                if isa(data_selected(i).Parent, 'matlab.ui.container.Tree')
                    data_num = data_num + 1;
                    app.Ddata_mark(i) = 1;
                else
                    app.Ddata_mark(i) = 0;
                end
            end
            if data_num < 2
                msg = 'Select at least 2 data node to merge';
                uiconfirm(app.MTOPlatformUIFigure, msg, 'error', 'Icon','warning');
                result = false;
                return;
            end
            
            data_selected = data_selected(app.Ddata_mark == 1);
            
            if ~app.DcheckMergeReps() || ~app.DcheckMergeAlgorithms()
                return;
            end
            
            % merge
            data_selected = app.DDataTree.SelectedNodes;
            data_selected = data_selected(app.Ddata_mark == 1);
            data_save.reps = data_selected(1).NodeData.reps;
            data_save.algo_cell = data_selected(1).NodeData.algo_cell;
            data_save.prob_cell = {};
            data_save.tasks_num_list = [];
            data_save.sub_pop = [];
            data_save.sub_eva = [];
            for i = 1:length(data_selected)
                data_save.sub_pop = [data_save.sub_pop; data_selected(i).NodeData.sub_pop];
                data_save.sub_eva = [data_save.sub_eva; data_selected(i).NodeData.sub_eva];
                data_save.tasks_num_list = [data_save.tasks_num_list; data_selected(i).NodeData.tasks_num_list];
                prob_start = length(data_save.prob_cell) + 1;
                data_save.prob_cell = [data_save.prob_cell; data_selected(i).NodeData.prob_cell];
                prob_end = length(data_save.prob_cell);
                data_save.result(prob_start:prob_end, :) = data_selected(i).NodeData.result;
            end
            
            app.DputDataNode('data (Merge Problems)', data_save);
            drawnow;
        end

        % Node text changed function: DDataTree
        function DDataTreeNodeTextChanged(app, event)
            % update data text
            
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % this is data text node
                node.NodeData.name = node.Text;
            else
                % this is data parameter node, can't change
                node.Text = node.NodeData;
            end
        end

        % Button pushed function: DUpButton
        function DUpButtonPushed(app, event)
            data_selected = app.DDataTree.SelectedNodes;
            data_mark = [];
            data_num = 0;
            for i = 1:length(data_selected)
                if isa(data_selected(i).Parent, 'matlab.ui.container.Tree')
                    data_num = data_num + 1;
                    data_mark(i) = 1;
                else
                    data_mark(i) = 0;
                end
            end
            data_selected = sort(app.DDataTree.SelectedNodes, 'descend');
            data_selected = data_selected(data_mark == 1);
            selected = [];
            
            % move up
            for i = 1:length(data_selected)
                parent = data_selected(i).Parent;
                for j = 1:length(parent.Children)
                    if parent.Children(j) == data_selected(i) && j > 1
                        move(parent.Children(j), parent.Children(j-1),'before');
                        selected = [selected, parent.Children(j-1)];
                        break;
                    elseif parent.Children(j) == data_selected(i) && j == 1
                        selected = [selected, parent.Children(j)];
                    end
                end
            end
            
            % change selected node
            app.DDataTree.SelectedNodes = selected;
            drawnow;
        end

        % Button pushed function: DDownButton
        function DDownButtonPushed(app, event)
            data_selected = app.DDataTree.SelectedNodes;
            
            data_mark = [];
            data_num = 0;
            for i = 1:length(data_selected)
                if isa(data_selected(i).Parent, 'matlab.ui.container.Tree')
                    data_num = data_num + 1;
                    data_mark(i) = 1;
                else
                    data_mark(i) = 0;
                end
            end
            data_selected = app.DDataTree.SelectedNodes;
            data_selected = sort(data_selected(data_mark == 1), 'descend');
            selected = [];
            
            % move down
            for i = length(data_selected):-1:1
                parent = data_selected(i).Parent;
                for j = 1:length(parent.Children)
                    if parent.Children(j) == data_selected(i) && j < length(parent.Children)
                        move(parent.Children(j), parent.Children(j+1));
                        selected = [selected, parent.Children(j+1)];
                        break;
                    elseif parent.Children(j) == data_selected(i) && j == length(parent.Children)
                        selected = [selected, parent.Children(j)];
                    end
                end
            end
            
            % change selected node
            app.DDataTree.SelectedNodes = selected;
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MTOPlatformUIFigure = uifigure('Visible', 'off');
            app.MTOPlatformUIFigure.Name = 'MTO Platform';
            app.MTOPlatformGridLayout = uigridlayout(app.MTOPlatformUIFigure);
            app.MTOPlatformTabGroup = uitabgroup(app.MTOPlatformGridLayout);
            app.TestTab = uitab(app.MTOPlatformTabGroup);
            app.TestTab.Title = 'Test';
            app.TestGridLayout = uigridlayout(app.TestTab);
            app.TPanel1 = uipanel(app.TestGridLayout);
            app.TP1GridLayout = uigridlayout(app.TPanel1);
            app.AlgorithmDropDownLabel = uilabel(app.TP1GridLayout);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.TAlgorithmDropDown = uidropdown(app.TP1GridLayout);
            app.TAlgorithmDropDown.Items = {};
            app.TAlgorithmDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TAlgorithmDropDownOpening, true);
            app.TAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @TAlgorithmDropDownValueChanged, true);
            app.TAlgorithmDropDown.Tooltip = {'Select algorithm'};
            app.TAlgorithmDropDown.Value = {};
            app.TAlgorithmTree = uitree(app.TP1GridLayout);
            app.TAlgorithmTree.Multiselect = 'on';
            app.TAlgorithmTree.NodeTextChangedFcn = createCallbackFcn(app, @TAlgorithmTreeNodeTextChanged, true);
            app.TAlgorithmTree.Editable = 'on';
            app.TProblemTree = uitree(app.TP1GridLayout);
            app.TProblemTree.Multiselect = 'on';
            app.TProblemTree.NodeTextChangedFcn = createCallbackFcn(app, @TProblemTreeNodeTextChanged, true);
            app.TProblemTree.Editable = 'on';
            app.TProblemDropDown = uidropdown(app.TP1GridLayout);
            app.TProblemDropDown.Items = {};
            app.TProblemDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TProblemDropDownOpening, true);
            app.TProblemDropDown.ValueChangedFcn = createCallbackFcn(app, @TProblemDropDownValueChanged, true);
            app.TProblemDropDown.Tooltip = {'Select problem'};
            app.TProblemDropDown.Value = {};
            app.ProblemDropDownLabel = uilabel(app.TP1GridLayout);
            app.ProblemDropDownLabel.Text = 'Problem';
            app.TTaskTypeDropDown = uidropdown(app.TP1GridLayout);
            app.TTaskTypeDropDown.Items = {'Multi', 'Many', 'Single'};
            app.TTaskTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TTaskTypeDropDownValueChanged, true);
            app.TTaskTypeDropDown.Value = 'Multi';
            app.TaskLabel_2 = uilabel(app.TP1GridLayout);
            app.TaskLabel_2.Tooltip = {'Single-task EA Option'};
            app.TaskLabel_2.Text = 'Task';
            app.SpecialLabel_2 = uilabel(app.TP1GridLayout);
            app.SpecialLabel_2.Tooltip = {'Single-task EA Option'};
            app.SpecialLabel_2.Text = 'Special';
            app.TSpecialTypeDropDown = uidropdown(app.TP1GridLayout);
            app.TSpecialTypeDropDown.Items = {'None', 'Competitive', 'Constrained', 'Expensive'};
            app.TSpecialTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TSpecialTypeDropDownValueChanged, true);
            app.TSpecialTypeDropDown.Value = 'None';
            app.TPanel2 = uipanel(app.TestGridLayout);
            app.TP2GridLayout = uigridlayout(app.TPanel2);
            app.TP21GridLayout = uigridlayout(app.TP2GridLayout);
            app.TShowTypeDropDown = uidropdown(app.TP21GridLayout);
            app.TShowTypeDropDown.Items = {'Tasks Figure (1D unified)', 'Tasks Figure (1D real)', 'Feasible Region (2D)', 'Convergence (Obj)'};
            app.TShowTypeDropDown.ItemsData = [1 2 3 4];
            app.TShowTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TShowTypeDropDownValueChanged, true);
            app.TShowTypeDropDown.Tooltip = {'Show type'};
            app.TShowTypeDropDown.Value = 1;
            app.TSaveButton = uibutton(app.TP21GridLayout, 'push');
            app.TSaveButton.ButtonPushedFcn = createCallbackFcn(app, @TSaveButtonPushed, true);
            app.TSaveButton.Tooltip = {''};
            app.TSaveButton.Text = 'Save';
            app.TP24GridLayout = uigridlayout(app.TP2GridLayout);
            app.TStartButton = uibutton(app.TP24GridLayout, 'push');
            app.TStartButton.ButtonPushedFcn = createCallbackFcn(app, @TStartButtonPushed, true);
            app.TStartButton.Tooltip = {''};
            app.TStartButton.Text = 'Start';
            app.TResetButton = uibutton(app.TP24GridLayout, 'push');
            app.TResetButton.ButtonPushedFcn = createCallbackFcn(app, @TResetButtonPushed, true);
            app.TResetButton.Tooltip = {''};
            app.TResetButton.Text = 'Reset';
            app.TUIAxes = uiaxes(app.TP2GridLayout);
            app.TPanel3 = uipanel(app.TestGridLayout);
            app.TP3GridLayout = uigridlayout(app.TPanel3);
            app.OutputTextAreaLabel = uilabel(app.TP3GridLayout);
            app.OutputTextAreaLabel.Text = 'Output';
            app.TOutputTextArea = uitextarea(app.TP3GridLayout);
            app.TOutputTextArea.Editable = 'off';
            app.Emailint_lyccugeducnLabel = uilabel(app.TP3GridLayout);
            app.Emailint_lyccugeducnLabel.Text = 'Email: int_lyc@cug.edu.cn';
            app.ExperimentTab = uitab(app.MTOPlatformTabGroup);
            app.ExperimentTab.Title = 'Experiment';
            app.ExperimentsGridLayout = uigridlayout(app.ExperimentTab);
            app.EPanel3 = uipanel(app.ExperimentsGridLayout);
            app.EP3GridLayout = uigridlayout(app.EPanel3);
            app.ETableTabGroup = uitabgroup(app.EP3GridLayout);
            app.ETableTab = uitab(app.ETableTabGroup);
            app.ETableTab.Title = 'Table';
            app.EP3TGridLayout = uigridlayout(app.ETableTab);
            app.EP3T1GridLayout = uigridlayout(app.EP3TGridLayout);
            app.ETestTypeDropDown = uidropdown(app.EP3T1GridLayout);
            app.ETestTypeDropDown.Items = {'None', 'Rank sum test', 'Signed rank test', 'FR rank'};
            app.ETestTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ETestTypeDropDownValueChanged, true);
            app.ETestTypeDropDown.Tooltip = {'Statistical Analysis (Only for Objective value)'};
            app.ETestTypeDropDown.Value = 'None';
            app.EAlgorithmDropDown = uidropdown(app.EP3T1GridLayout);
            app.EAlgorithmDropDown.Items = {'Algorithm'};
            app.EAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @EAlgorithmDropDownValueChanged, true);
            app.EAlgorithmDropDown.Tooltip = {'Statistical Analysis main Algorithm (Only for Objective value)'};
            app.EAlgorithmDropDown.Value = 'Algorithm';
            app.EShowTypeDropDown = uidropdown(app.EP3T1GridLayout);
            app.EShowTypeDropDown.Items = {'Mean', 'Mean&Std', 'Mean&CV', 'Mean&CV&FR', 'Std', 'Median', 'Best', 'Worst', 'CV', 'FR'};
            app.EShowTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EShowTypeDropDownValueChanged, true);
            app.EShowTypeDropDown.Tooltip = {'Data Type (Only for Objective value)'};
            app.EShowTypeDropDown.Value = 'Mean';
            app.EDataTypeDropDown = uidropdown(app.EP3T1GridLayout);
            app.EDataTypeDropDown.Items = {'Reps', 'Obj', 'Score', 'min(Obj)', 'Time used'};
            app.EDataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EDataTypeDropDownValueChanged, true);
            app.EDataTypeDropDown.Tooltip = {'Show Type'};
            app.EDataTypeDropDown.Value = 'Reps';
            app.EHighlightTypeDropDown = uidropdown(app.EP3T1GridLayout);
            app.EHighlightTypeDropDown.Items = {'None', 'Best', 'Best&Worst'};
            app.EHighlightTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EHighlightTypeDropDownValueChanged, true);
            app.EHighlightTypeDropDown.Tooltip = {'Highlight type'};
            app.EHighlightTypeDropDown.Value = 'Best&Worst';
            app.ESaveTableButton = uibutton(app.EP3T1GridLayout, 'push');
            app.ESaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @ESaveTableButtonPushed, true);
            app.ESaveTableButton.Tooltip = {'Save current table to file'};
            app.ESaveTableButton.Text = 'Save';
            app.EDataFormatEditField = uieditfield(app.EP3T1GridLayout, 'text');
            app.EDataFormatEditField.ValueChangedFcn = createCallbackFcn(app, @EDataFormatEditFieldValueChanged, true);
            app.EDataFormatEditField.Tooltip = {'Data Format Str'};
            app.EDataFormatEditField.Value = '%d';
            app.EUITable = uitable(app.EP3TGridLayout);
            app.EUITable.ColumnName = '';
            app.EUITable.RowName = {};
            app.EFigureTab = uitab(app.ETableTabGroup);
            app.EFigureTab.Title = 'Figure';
            app.EP3FGridLayout = uigridlayout(app.EFigureTab);
            app.EP3F1GridLayout = uigridlayout(app.EP3FGridLayout);
            app.EProblemsDropDown = uidropdown(app.EP3F1GridLayout);
            app.EProblemsDropDown.Items = {'Problem '};
            app.EProblemsDropDown.ValueChangedFcn = createCallbackFcn(app, @EProblemsDropDownValueChanged, true);
            app.EProblemsDropDown.Tooltip = {'Problem or Task'};
            app.EProblemsDropDown.Value = 'Problem ';
            app.EYLimTypeDropDown = uidropdown(app.EP3F1GridLayout);
            app.EYLimTypeDropDown.Items = {'log(Objective Value)', 'Objective Value'};
            app.EYLimTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EYLimTypeDropDownValueChanged, true);
            app.EYLimTypeDropDown.Tooltip = {'YLim Type'};
            app.EYLimTypeDropDown.Value = 'log(Objective Value)';
            app.ESaveAllFigureButton = uibutton(app.EP3F1GridLayout, 'push');
            app.ESaveAllFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ESaveAllFigureButtonPushed, true);
            app.ESaveAllFigureButton.Tooltip = {'Select save dir and it will save all figures to ''dir/Figure/'''};
            app.ESaveAllFigureButton.Text = 'Save All Figure';
            app.EFigureTypeDropDown = uidropdown(app.EP3F1GridLayout);
            app.EFigureTypeDropDown.Items = {'eps', 'png', 'pdf'};
            app.EFigureTypeDropDown.Tooltip = {'Save Figure Type'};
            app.EFigureTypeDropDown.Value = 'eps';
            app.EMarkerIndicesEditField = uieditfield(app.EP3F1GridLayout, 'numeric');
            app.EMarkerIndicesEditField.Limits = [0 Inf];
            app.EMarkerIndicesEditField.RoundFractionalValues = 'on';
            app.EMarkerIndicesEditField.ValueChangedFcn = createCallbackFcn(app, @EMarkerIndicesEditFieldValueChanged, true);
            app.EMarkerIndicesEditField.Tooltip = {'Marker Num'};
            app.EMarkerIndicesEditField.Value = 10;
            app.EConvergenceTypeDropDown = uidropdown(app.EP3F1GridLayout);
            app.EConvergenceTypeDropDown.Items = {'Obj', 'min(Obj)'};
            app.EConvergenceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EConvergenceTypeDropDownValueChanged, true);
            app.EConvergenceTypeDropDown.Tooltip = {'Data Type'};
            app.EConvergenceTypeDropDown.Value = 'Obj';
            app.EXLimTypeDropDown = uidropdown(app.EP3F1GridLayout);
            app.EXLimTypeDropDown.Items = {'Evaluation', 'Generation'};
            app.EXLimTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EXLimTypeDropDownValueChanged, true);
            app.EXLimTypeDropDown.Tooltip = {'Data Type'};
            app.EXLimTypeDropDown.Value = 'Evaluation';
            app.EConvergenceTrendUIAxes = uiaxes(app.EP3FGridLayout);
            xlabel(app.EConvergenceTrendUIAxes, 'Evaluation')
            ylabel(app.EConvergenceTrendUIAxes, 'Objective Value')
            app.EPanel1 = uipanel(app.ExperimentsGridLayout);
            app.EP1GridLayout = uigridlayout(app.EPanel1);
            app.EProblemsAddButton = uibutton(app.EP1GridLayout, 'push');
            app.EProblemsAddButton.ButtonPushedFcn = createCallbackFcn(app, @EProblemsAddButtonPushed, true);
            app.EProblemsAddButton.Tooltip = {'Add selected problems'};
            app.EProblemsAddButton.Text = 'Add';
            app.EAlgorithmsAddButton = uibutton(app.EP1GridLayout, 'push');
            app.EAlgorithmsAddButton.ButtonPushedFcn = createCallbackFcn(app, @EAlgorithmsAddButtonPushed, true);
            app.EAlgorithmsAddButton.Tooltip = {'Add selected algorithms'};
            app.EAlgorithmsAddButton.Text = 'Add';
            app.ERepsEditField = uieditfield(app.EP1GridLayout, 'numeric');
            app.ERepsEditField.Limits = [1 Inf];
            app.ERepsEditField.RoundFractionalValues = 'on';
            app.ERepsEditField.ValueDisplayFormat = '%d';
            app.ERepsEditField.Value = 30;
            app.ERunTimesEditFieldLabel = uilabel(app.EP1GridLayout);
            app.ERunTimesEditFieldLabel.Text = 'Reps';
            app.EAlgorithmsListBox = uilistbox(app.EP1GridLayout);
            app.EAlgorithmsListBox.Items = {};
            app.EAlgorithmsListBox.Multiselect = 'on';
            app.EAlgorithmsListBox.Value = {};
            app.AlgorithmsLabel = uilabel(app.EP1GridLayout);
            app.AlgorithmsLabel.Text = 'Algorithms';
            app.EProblemsListBox = uilistbox(app.EP1GridLayout);
            app.EProblemsListBox.Items = {};
            app.EProblemsListBox.Multiselect = 'on';
            app.EProblemsListBox.Value = {};
            app.ProblemsLabel = uilabel(app.EP1GridLayout);
            app.ProblemsLabel.Text = 'Problems';
            app.ParallelDropDownLabel = uilabel(app.EP1GridLayout);
            app.ParallelDropDownLabel.Text = 'Parallel';
            app.EParallelDropDown = uidropdown(app.EP1GridLayout);
            app.EParallelDropDown.Items = {'Enable', 'Disable'};
            app.EParallelDropDown.ItemsData = [1 0];
            app.EParallelDropDown.Value = 1;
            app.TaskLabel = uilabel(app.EP1GridLayout);
            app.TaskLabel.Tooltip = {'Single-task EA Option'};
            app.TaskLabel.Text = 'Task';
            app.ETaskTypeDropDown = uidropdown(app.EP1GridLayout);
            app.ETaskTypeDropDown.Items = {'Multi', 'Many', 'Single'};
            app.ETaskTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ETaskTypeDropDownValueChanged, true);
            app.ETaskTypeDropDown.Value = 'Multi';
            app.ELoadDataButton = uibutton(app.EP1GridLayout, 'push');
            app.ELoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ELoadDataButtonPushed, true);
            app.ELoadDataButton.Tooltip = {'Load data_save.mat to show detials'};
            app.ELoadDataButton.Text = 'Load Data';
            app.SpecialLabel = uilabel(app.EP1GridLayout);
            app.SpecialLabel.Tooltip = {'Single-task EA Option'};
            app.SpecialLabel.Text = 'Special';
            app.ESpecialTypeDropDown = uidropdown(app.EP1GridLayout);
            app.ESpecialTypeDropDown.Items = {'None', 'Competitive', 'Constrained', 'Expensive'};
            app.ESpecialTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ESpecialTypeDropDownValueChanged, true);
            app.ESpecialTypeDropDown.Value = 'None';
            app.EPanel2 = uipanel(app.ExperimentsGridLayout);
            app.EP2GridLayout = uigridlayout(app.EPanel2);
            app.EStartButton = uibutton(app.EP2GridLayout, 'push');
            app.EStartButton.ButtonPushedFcn = createCallbackFcn(app, @EStartButtonPushed, true);
            app.EStartButton.Text = 'Start';
            app.EPauseButton = uibutton(app.EP2GridLayout, 'push');
            app.EPauseButton.ButtonPushedFcn = createCallbackFcn(app, @EPauseButtonPushed, true);
            app.EPauseButton.Enable = 'off';
            app.EPauseButton.Text = 'Pause';
            app.EStopButton = uibutton(app.EP2GridLayout, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Enable = 'off';
            app.EStopButton.Text = 'Stop';
            app.EAlgorithmsTree = uitree(app.EP2GridLayout);
            app.EAlgorithmsTree.Multiselect = 'on';
            app.EAlgorithmsTree.NodeTextChangedFcn = createCallbackFcn(app, @EAlgorithmsTreeNodeTextChanged, true);
            app.EAlgorithmsTree.Editable = 'on';
            app.EProblemsTree = uitree(app.EP2GridLayout);
            app.EProblemsTree.Multiselect = 'on';
            app.EProblemsTree.NodeTextChangedFcn = createCallbackFcn(app, @EProblemsTreeNodeTextChanged, true);
            app.EProblemsTree.Editable = 'on';
            app.ESelectedAlgorithmsLabel = uilabel(app.EP2GridLayout);
            app.ESelectedAlgorithmsLabel.Text = 'Selected Algo';
            app.ESelectedProblemsLabel = uilabel(app.EP2GridLayout);
            app.ESelectedProblemsLabel.Text = 'Selected Prob';
            app.EAlgorithmsDelButton = uibutton(app.EP2GridLayout, 'push');
            app.EAlgorithmsDelButton.ButtonPushedFcn = createCallbackFcn(app, @EAlgorithmsDelButtonPushed, true);
            app.EAlgorithmsDelButton.Tooltip = {'Delete selected algorithms'};
            app.EAlgorithmsDelButton.Text = 'Delete';
            app.EProblemsDelButton = uibutton(app.EP2GridLayout, 'push');
            app.EProblemsDelButton.ButtonPushedFcn = createCallbackFcn(app, @EProblemsDelButtonPushed, true);
            app.EProblemsDelButton.Tooltip = {'Delete selected problems'};
            app.EProblemsDelButton.Text = 'Delete';
            app.ESaveDataButton = uibutton(app.EP2GridLayout, 'push');
            app.ESaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @ESaveDataButtonPushed, true);
            app.ESaveDataButton.Tooltip = {'Save finished data to mat file'};
            app.ESaveDataButton.Text = 'Save Data';
            app.DataProcessTab = uitab(app.MTOPlatformTabGroup);
            app.DataProcessTab.Title = 'Data Process';
            app.DataProcessGridLayout = uigridlayout(app.DataProcessTab);
            app.DPanel1 = uipanel(app.DataProcessGridLayout);
            app.DP1GridLayout = uigridlayout(app.DPanel1);
            app.DDataProcessModuleLabel = uilabel(app.DP1GridLayout);
            app.DDataProcessModuleLabel.Text = 'Data Process Module';
            app.DP1Panel1 = uipanel(app.DP1GridLayout);
            app.DP1P1GridLayout = uigridlayout(app.DP1Panel1);
            app.DLoadDataButton = uibutton(app.DP1P1GridLayout, 'push');
            app.DLoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @DLoadDataButtonPushed, true);
            app.DLoadDataButton.Text = 'Load Data';
            app.DDeleteDataButton = uibutton(app.DP1P1GridLayout, 'push');
            app.DDeleteDataButton.ButtonPushedFcn = createCallbackFcn(app, @DDeleteDataButtonPushed, true);
            app.DDeleteDataButton.Text = 'Delete Data';
            app.DSaveDataButton = uibutton(app.DP1P1GridLayout, 'push');
            app.DSaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @DSaveDataButtonPushed, true);
            app.DSaveDataButton.Text = 'Save Data';
            app.DLoadDataorSelectandDeleteSaveDataLabel_3 = uilabel(app.DP1P1GridLayout);
            app.DLoadDataorSelectandDeleteSaveDataLabel_3.Text = 'Select data node, click Delete/Save';
            app.DLoadDataorSelectandDeleteSaveDataLabel_4 = uilabel(app.DP1P1GridLayout);
            app.DLoadDataorSelectandDeleteSaveDataLabel_4.Text = 'Load data to tree';
            app.DP1Panel2 = uipanel(app.DP1GridLayout);
            app.DP1P2GridLayout = uigridlayout(app.DP1Panel2);
            app.DSelectandSplitDataLabel = uilabel(app.DP1P2GridLayout);
            app.DSelectandSplitDataLabel.Text = 'Select data node, click Split button';
            app.DRepsSplitButton = uibutton(app.DP1P2GridLayout, 'push');
            app.DRepsSplitButton.ButtonPushedFcn = createCallbackFcn(app, @DRepsSplitButtonPushed, true);
            app.DRepsSplitButton.Text = 'Reps Split';
            app.DAlgorithmsSplitButton = uibutton(app.DP1P2GridLayout, 'push');
            app.DAlgorithmsSplitButton.ButtonPushedFcn = createCallbackFcn(app, @DAlgorithmsSplitButtonPushed, true);
            app.DAlgorithmsSplitButton.Text = 'Algorithm Split';
            app.DProblemsSplitButton = uibutton(app.DP1P2GridLayout, 'push');
            app.DProblemsSplitButton.ButtonPushedFcn = createCallbackFcn(app, @DProblemsSplitButtonPushed, true);
            app.DProblemsSplitButton.Text = 'Problem Split';
            app.DP1Panel3 = uipanel(app.DP1GridLayout);
            app.DP1P3GridLayout = uigridlayout(app.DP1Panel3);
            app.DSelectandMergeDataLabel = uilabel(app.DP1P3GridLayout);
            app.DSelectandMergeDataLabel.Text = 'Select data node, click Merge button';
            app.DRepsMergeButton = uibutton(app.DP1P3GridLayout, 'push');
            app.DRepsMergeButton.ButtonPushedFcn = createCallbackFcn(app, @DRepsMergeButtonPushed, true);
            app.DRepsMergeButton.Text = 'Reps Merge';
            app.DAlgorithmsMergeButton = uibutton(app.DP1P3GridLayout, 'push');
            app.DAlgorithmsMergeButton.ButtonPushedFcn = createCallbackFcn(app, @DAlgorithmsMergeButtonPushed, true);
            app.DAlgorithmsMergeButton.Text = 'Algorithm Merge';
            app.DProblemsMergeButton = uibutton(app.DP1P3GridLayout, 'push');
            app.DProblemsMergeButton.ButtonPushedFcn = createCallbackFcn(app, @DProblemsMergeButtonPushed, true);
            app.DProblemsMergeButton.Text = 'Problem Merge';
            app.DP1Panel4 = uipanel(app.DP1GridLayout);
            app.DP1P4GridLayout = uigridlayout(app.DP1Panel4);
            app.DUpandDownDataLabel = uilabel(app.DP1P4GridLayout);
            app.DUpandDownDataLabel.Text = 'Select data node, click Up or Down button';
            app.DUpButton = uibutton(app.DP1P4GridLayout, 'push');
            app.DUpButton.ButtonPushedFcn = createCallbackFcn(app, @DUpButtonPushed, true);
            app.DUpButton.Text = 'UP';
            app.DDownButton = uibutton(app.DP1P4GridLayout, 'push');
            app.DDownButton.ButtonPushedFcn = createCallbackFcn(app, @DDownButtonPushed, true);
            app.DDownButton.Text = 'Down';
            app.DPanel2 = uipanel(app.DataProcessGridLayout);
            app.DP2GridLayout = uigridlayout(app.DPanel2);
            app.DDataTree = uitree(app.DP2GridLayout);
            app.DDataTree.Multiselect = 'on';
            app.DDataTree.NodeTextChangedFcn = createCallbackFcn(app, @DDataTreeNodeTextChanged, true);
            app.DDataTree.Editable = 'on';
            app.ESelectedAlgoContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            app.ESelectedAlgoContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ESelectedAlgoContextMenuOpening, true);
            % Assign app.ESelectedAlgoContextMenu
            app.TAlgorithmTree.ContextMenu = app.ESelectedAlgoContextMenu;
            app.EAlgorithmsTree.ContextMenu = app.ESelectedAlgoContextMenu;
            app.SelectedAlgoSelectAllMenu = uimenu(app.ESelectedAlgoContextMenu);
            app.SelectedAlgoSelectAllMenu.Text = 'Select All';
            app.DDataContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            app.DDataContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @DDataContextMenuOpening, true);
            % Assign app.DDataContextMenu
            app.DDataTree.ContextMenu = app.DDataContextMenu;
            app.SelectedAlgoSelectAllMenu_2 = uimenu(app.DDataContextMenu);
            app.SelectedAlgoSelectAllMenu_2.Text = 'Select All';
            app.ESelectedProbContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            % Assign app.ESelectedProbContextMenu
            app.TProblemTree.ContextMenu = app.ESelectedProbContextMenu;
            app.EProblemsTree.ContextMenu = app.ESelectedProbContextMenu;
            app.SelectedProbSelectAllMenu = uimenu(app.ESelectedProbContextMenu);
            app.SelectedProbSelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ESelectedProbContextMenuOpening, true);
            app.SelectedProbSelectAllMenu.Text = 'Select All';
            app.EAlgorithmsContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            app.EAlgorithmsContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @EAlgorithmsContextMenuOpening, true);
            % Assign app.EAlgorithmsContextMenu
            app.EAlgorithmsListBox.ContextMenu = app.EAlgorithmsContextMenu;
            app.AlgorithmsSelectAllMenu = uimenu(app.EAlgorithmsContextMenu);
            app.AlgorithmsSelectAllMenu.Text = 'Select All';
            app.EProblemsContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            % Assign app.EProblemsContextMenu
            app.EProblemsListBox.ContextMenu = app.EProblemsContextMenu;
            app.ProblemsSelectAllMenu = uimenu(app.EProblemsContextMenu);
            app.ProblemsSelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @EProblemsContextMenuOpening, true);
            app.ProblemsSelectAllMenu.Text = 'Select All';
            % Show the figure after all components are created
            app.MTOPlatformUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTO_Platform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MTOPlatformUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MTOPlatformUIFigure)
        end
    end
end